classdef design_page_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        soyourehappyandinloveheresyourHarrysongreccomendationPanel_8  matlab.ui.container.Panel
        Hyperlink_7                     matlab.ui.control.Hyperlink
        Image6_8                        matlab.ui.control.Image
        EditField_8                     matlab.ui.control.EditField
        soyourehappyandinloveheresyourHarrysongreccomendationPanel_7  matlab.ui.container.Panel
        Hyperlink_6                     matlab.ui.control.Hyperlink
        Image6_7                        matlab.ui.control.Image
        EditField_7                     matlab.ui.control.EditField
        soyourehappyandinloveheresyourHarrysongreccomendationPanel_6  matlab.ui.container.Panel
        Hyperlink_5                     matlab.ui.control.Hyperlink
        Image6_6                        matlab.ui.control.Image
        EditField_6                     matlab.ui.control.EditField
        soyourehappyandinloveheresyourHarrysongreccomendationPanel_5  matlab.ui.container.Panel
        Hyperlink_4                     matlab.ui.control.Hyperlink
        Image6_5                        matlab.ui.control.Image
        EditField_5                     matlab.ui.control.EditField
        soyourehappyandinloveheresyourHarrysongreccomendationPanel_4  matlab.ui.container.Panel
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Image6_4                        matlab.ui.control.Image
        EditField_4                     matlab.ui.control.EditField
        soyourehappyandinloveheresyourHarrysongreccomendationPanel_3  matlab.ui.container.Panel
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Image6_3                        matlab.ui.control.Image
        EditField_3                     matlab.ui.control.EditField
        soyourehappyandinloveheresyourHarrysongreccomendationPanel_2  matlab.ui.container.Panel
        Hyperlink                       matlab.ui.control.Hyperlink
        Image6_2                        matlab.ui.control.Image
        EditField_2                     matlab.ui.control.EditField
        SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel  matlab.ui.container.Panel
        SubmitButton_5                  matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_5  matlab.ui.container.ButtonGroup
        ItscomplicatedButton_3          matlab.ui.control.RadioButton
        SingleandchillingButton_3       matlab.ui.control.RadioButton
        Imheartbroken3Button_3          matlab.ui.control.RadioButton
        IminloveButton_3                matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_5   matlab.ui.container.ButtonGroup
        SadButton_5                     matlab.ui.control.RadioButton
        HappyButton_5                   matlab.ui.control.RadioButton
        SoyoureNiallHoranbutwhatNiallHoransongareyouPanel  matlab.ui.container.Panel
        SubmitButton_4                  matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_4  matlab.ui.container.ButtonGroup
        ItscomplicatedButton_2          matlab.ui.control.RadioButton
        SingleandchillingButton_2       matlab.ui.control.RadioButton
        Imheartbroken3Button_2          matlab.ui.control.RadioButton
        IminloveButton_2                matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_4   matlab.ui.container.ButtonGroup
        SadButton_4                     matlab.ui.control.RadioButton
        HappyButton_4                   matlab.ui.control.RadioButton
        SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel  matlab.ui.container.Panel
        SubmitButton_3                  matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_3  matlab.ui.container.ButtonGroup
        ItscomplicatedButton            matlab.ui.control.RadioButton
        SingleandchillingButton         matlab.ui.control.RadioButton
        Imheartbroken3Button            matlab.ui.control.RadioButton
        IminloveButton                  matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_3   matlab.ui.container.ButtonGroup
        SadButton_3                     matlab.ui.control.RadioButton
        HappyButton_3                   matlab.ui.control.RadioButton
        SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel  matlab.ui.container.Panel
        SubmitButton_6                  matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_6  matlab.ui.container.ButtonGroup
        ItscomplicatedButton_4          matlab.ui.control.RadioButton
        SingleandchillingButton_4       matlab.ui.control.RadioButton
        Imheartbroken3Button_4          matlab.ui.control.RadioButton
        IminloveButton_4                matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_6   matlab.ui.container.ButtonGroup
        SadButton_6                     matlab.ui.control.RadioButton
        HappyButton_6                   matlab.ui.control.RadioButton
        soyourehappyandinloveheresyourHarrysongreccomendationPanel  matlab.ui.container.Panel
        soyourehappyandinloveheresyourHarrysongreccomendationPanel_11  matlab.ui.container.Panel
        Hyperlink2_5                    matlab.ui.control.Hyperlink
        Image6_19                       matlab.ui.control.Image
        Image6_18                       matlab.ui.control.Image
        Image6_17                       matlab.ui.control.Image
        EditField_11                    matlab.ui.control.EditField
        soyourehappyandinloveheresyourHarrysongreccomendationPanel_10  matlab.ui.container.Panel
        Hyperlink2_4                    matlab.ui.control.Hyperlink
        Image6_16                       matlab.ui.control.Image
        Image6_15                       matlab.ui.control.Image
        Image6_14                       matlab.ui.control.Image
        EditField_10                    matlab.ui.control.EditField
        soyourehappyandinloveheresyourHarrysongreccomendationPanel_9  matlab.ui.container.Panel
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Image6_11                       matlab.ui.control.Image
        Image6_10                       matlab.ui.control.Image
        Image6_9                        matlab.ui.control.Image
        EditField_9                     matlab.ui.control.EditField
        Hyperlink2_3                    matlab.ui.control.Hyperlink
        Image6_13                       matlab.ui.control.Image
        Image6_12                       matlab.ui.control.Image
        EditField                       matlab.ui.control.EditField
        SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel  matlab.ui.container.Panel
        SoyoureHarryStylesbutwhatHarryStylessongareyouPanel  matlab.ui.container.Panel
        SubmitButton                    matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup  matlab.ui.container.ButtonGroup
        ItscomplicatedButton_6          matlab.ui.control.RadioButton
        SingleandchillingButton_6       matlab.ui.control.RadioButton
        Imheartbroken3Button_6          matlab.ui.control.RadioButton
        IminloveButton_6                matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup     matlab.ui.container.ButtonGroup
        SadButton                       matlab.ui.control.RadioButton
        HappyButton                     matlab.ui.control.RadioButton
        SubmitButton_2                  matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_2  matlab.ui.container.ButtonGroup
        ItscomplicatedButton_5          matlab.ui.control.RadioButton
        SingleandchillingButton_5       matlab.ui.control.RadioButton
        Imheartbroken3Button_5          matlab.ui.control.RadioButton
        IminloveButton_5                matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_2   matlab.ui.container.ButtonGroup
        SadButton_2                     matlab.ui.control.RadioButton
        HappyButton_2                   matlab.ui.control.RadioButton
        WhichOneDirectionMemberAreYouPanel  matlab.ui.container.Panel
        continueButton_5                matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_4  matlab.ui.container.Panel
        continueButton                  matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_6  matlab.ui.container.Panel
        continueButton_6                matlab.ui.control.Button
        TextArea_6                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_5  matlab.ui.container.Panel
        continueButton_3                matlab.ui.control.Button
        TextArea_5                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_3  matlab.ui.container.Panel
        continueButton_2                matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_2  matlab.ui.container.Panel
        continueButton_4                matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        WhichOneDirectionMemberAreYouLabel  matlab.ui.control.Label
        SUBMITButton                    matlab.ui.control.Button
        Q1WhatisyourfavoritefoodButtonGroup  matlab.ui.container.ButtonGroup
        MexicanfoodButton               matlab.ui.control.RadioButton
        pastaButton                     matlab.ui.control.RadioButton
        WhoneedsfoodwhenyouhavebeerButton  matlab.ui.control.RadioButton
        fastfoodButton                  matlab.ui.control.RadioButton
        fruitsandveggiesButton          matlab.ui.control.RadioButton
        Q2WhatisyourfavoriteseasonButtonGroup  matlab.ui.container.ButtonGroup
        IlikethemallButton              matlab.ui.control.RadioButton
        fallButton                      matlab.ui.control.RadioButton
        springButton                    matlab.ui.control.RadioButton
        winterButton                    matlab.ui.control.RadioButton
        summerButton                    matlab.ui.control.RadioButton
        Q3WhatwouldyouridealfirstdatebeButtonGroup  matlab.ui.container.ButtonGroup
        goingtoacarnivalButton          matlab.ui.control.RadioButton
        fancydinnerdateButton           matlab.ui.control.RadioButton
        stayinginandplayinggamesButton  matlab.ui.control.RadioButton
        goingforadriveButton            matlab.ui.control.RadioButton
        iceskatingButton                matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup_3  matlab.ui.container.ButtonGroup
        APMAEconwithanentrepreneurshipcertificateButton  matlab.ui.control.RadioButton
        VISAButton                      matlab.ui.control.RadioButton
        HistoryButton                   matlab.ui.control.RadioButton
        MCMButton                       matlab.ui.control.RadioButton
        LiteraryartsButton              matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup  matlab.ui.container.ButtonGroup
        BeaglepuppyButton               matlab.ui.control.RadioButton
        BlackkittenButton               matlab.ui.control.RadioButton
        GoldenretrieverpuppyButton      matlab.ui.control.RadioButton
        AustralianshepherdpuppyButton   matlab.ui.control.RadioButton
        CalicokittenButton              matlab.ui.control.RadioButton
        Q7WhatisyourfavoritecolorButtonGroup  matlab.ui.container.ButtonGroup
        RedButton                       matlab.ui.control.RadioButton
        YellowButton                    matlab.ui.control.RadioButton
        OrangeButton                    matlab.ui.control.RadioButton
        BlueButton                      matlab.ui.control.RadioButton
        GreenButton                     matlab.ui.control.RadioButton
        Q6WhichroledoyouhaveinyourfriendgroupButtonGroup  matlab.ui.container.ButtonGroup
        DadfriendButton                 matlab.ui.control.RadioButton
        TheonepeoplegotoforadviceButton  matlab.ui.control.RadioButton
        CrazyuncleButton                matlab.ui.control.RadioButton
        ClassclownButton                matlab.ui.control.RadioButton
        MomfriendButton                 matlab.ui.control.RadioButton
        Q5WhichwouldyouratherdoinyourfreetimeButtonGroup  matlab.ui.container.ButtonGroup
        GooutButton                     matlab.ui.control.RadioButton
        DraworpaintButton               matlab.ui.control.RadioButton
        GolfButton                      matlab.ui.control.RadioButton
        PlaysoccerButton                matlab.ui.control.RadioButton
        PilatesmeditationButton         matlab.ui.control.RadioButton
        Q4WhoisyourfavoriteoftheseartistsButtonGroup  matlab.ui.container.ButtonGroup
        ABoogieButton                   matlab.ui.control.RadioButton
        SiaButton                       matlab.ui.control.RadioButton
        LewisCapaldiButton              matlab.ui.control.RadioButton
        SteveAokiButton                 matlab.ui.control.RadioButton
        LizzoButton                     matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            
score_harry = 0; %% INITIALIZE SCORES AND SET ALL TO 0
score_louis = 0;
score_niall = 0;
score_zayn = 0;
score_liam = 0;
app.WhichOneDirectionMemberAreYouPanel.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_2.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_3.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_4.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_5.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_6.Visible = 'off';
app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible='off';
app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible='off';
app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Visible='off';
app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Visible='off';
app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Visible='off';
app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible='off';
app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Visible='off';

%%QUESTION 1
if app.fruitsandveggiesButton.Value == true
      score_harry = score_harry + 1;
elseif app.fastfoodButton.Value ==true
      score_louis = score_louis + 1;
elseif app.WhoneedsfoodwhenyouhavebeerButton.Value ==true
      score_niall = score_niall + 1;
elseif app.pastaButton.Value ==true
      score_zayn = score_zayn + 1;
else app.MexicanfoodButton.Value ==true;
      score_liam = score_liam + 1;
end

%%QUESTION 2
if app.summerButton.Value ==true
      score_harry = score_harry + 1;
elseif app.winterButton.Value ==true
      score_louis = score_louis + 1;
elseif app.springButton.Value ==true
      score_niall = score_niall + 1;
elseif app.fallButton.Value ==true
      score_zayn = score_zayn + 1;
else app.IlikethemallButton.Value ==true;
      score_liam = score_liam + 4;
end

%%QUESTION 3
if app.iceskatingButton.Value ==true
      score_harry = score_harry + 1;
elseif app.goingforadriveButton.Value ==true
      score_louis = score_louis + 1;
elseif app.stayinginandplayinggamesButton.Value ==true
      score_niall = score_niall + 1;
elseif app.fancydinnerdateButton.Value ==true
      score_zayn = score_zayn + 1;
else app.goingtoacarnivalButton.Value ==true;
      score_liam = score_liam + 1;
end

%%QUESTION 4
if app.LizzoButton.Value == true
     score_harry = score_harry + 1;
elseif app.SteveAokiButton.Value == true
     score_louis = score_louis + 1;
elseif app.LewisCapaldiButton.Value == true
     score_niall = score_niall + 1;
elseif app.SiaButton.Value== true
     score_zayn = score_zayn + 1;
else app.ABoogieButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 5
if app.PilatesmeditationButton.Value == true
     score_harry = score_harry + 1;
elseif app.PlaysoccerButton.Value == true
     score_louis = score_louis + 1;
elseif app.GolfButton.Value == true
     score_niall = score_niall + 1;
elseif app.DraworpaintButton.Value == true
     score_zayn = score_zayn + 1;
else app.GooutButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 6
if app.MomfriendButton.Value == true
     score_harry = score_harry + 1;
elseif app.ClassclownButton.Value == true
     score_louis = score_louis + 1;
elseif app.CrazyuncleButton.Value == true
     score_niall = score_niall + 1;
elseif app.TheonepeoplegotoforadviceButton.Value == true
     score_zayn = score_zayn + 1;
else app.DadfriendButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 7
if app.GreenButton.Value == true
   score_harry = score_harry + 1;
elseif app.BlueButton.Value == true
   score_louis = score_louis + 1;
elseif app.OrangeButton.Value == true
   score_niall = score_niall + 1;
elseif app.YellowButton.Value == true
   score_zayn = score_zayn + 1;
else app.RedButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 8
if app.CalicokittenButton.Value == true
   score_harry = score_harry + 1;
elseif app.AustralianshepherdpuppyButton.Value == true
   score_louis = score_louis + 1;
elseif app.GoldenretrieverpuppyButton.Value == true
   score_niall = score_niall + 1;
elseif app.BlackkittenButton.Value == true
   score_zayn = score_zayn + 1;
else app.BeaglepuppyButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 9
if app.LiteraryartsButton.Value == true
   score_harry = score_harry + 1;
elseif app.MCMButton.Value == true
   score_louis = score_louis + 1;
elseif app.HistoryButton.Value == true
   score_niall = score_niall + 1;
elseif app.VISAButton.Value == true
   score_zayn = score_zayn + 1;
else app.APMAEconwithanentrepreneurshipcertificateButton.Value == true;
   score_liam = score_liam + 1;
end

%%RESULTS SECTION
if score_harry > score_louis & score_harry > score_niall & score_harry > score_zayn & score_harry > score_liam;
   disp('Harry Styles!');
   app.WhichOneDirectionMemberAreYouPanel.Visible = 'on';
elseif score_louis > score_harry & score_louis > score_niall & score_louis > score_zayn & score_louis > score_liam;
   disp('Louis Tomlinson!');
   app.WhichOneDirectionMemberAreYouPanel_3.Visible = 'on';
elseif score_niall > score_harry & score_niall > score_louis & score_niall > score_zayn & score_niall > score_liam;
   disp('Niall Horan!');
   app.WhichOneDirectionMemberAreYouPanel_5.Visible = 'on';
elseif score_zayn > score_harry & score_zayn > score_niall & score_zayn > score_liam & score_zayn > score_louis;
   disp('Zayn Malik!');
   app.WhichOneDirectionMemberAreYouPanel_2.Visible = 'on';
elseif score_liam > score_harry & score_liam > score_niall & score_liam > score_zayn & score_liam > score_louis;
   disp('Liam Payne!');
   app.WhichOneDirectionMemberAreYouPanel_4.Visible = 'on';
else score_harry==score_louis | score_harry==score_niall|score_harry==score_zayn|score_harry==score_liam|score_louis==score_niall|score_louis==score_zayn|score_louis==score_liam|score_niall==score_zayn|score_niall==score_liam|score_zayn==score_liam;
    disp('Taylor Swift')
    app.WhichOneDirectionMemberAreYouPanel_6.Visible = 'on';
end

        end

        % Button pushed function: continueButton_5
        function continueButton_5Pushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel.Visible='off';
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible='on';
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
           app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible='off';
            if app.HappyButton.Value==true && app.IminloveButton_6.Value==true
                app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible='on';
            end
        end

        % Button pushed function: continueButton
        function continueButtonPushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel_4.Visible='off';
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Visible='on';
        end

        % Button pushed function: continueButton_6
        function continueButton_6Pushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel_6.Visible='off';
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Visible='on';
        end

        % Button pushed function: continueButton_3
        function continueButton_3Pushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel_3.Visible='off';
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Visible='on';
        end

        % Button pushed function: continueButton_2
        function continueButton_2Pushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel_2.Visible='off';
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible='on';
        end

        % Button pushed function: continueButton_4
        function continueButton_4Pushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel_4.Visible='off';
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Visible='on';
        end

        % Button pushed function: SubmitButton_2
        function SubmitButton_2Pushed(app, event)
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible='off';
            if app.HappyButton_2.Value==true && app.IminloveButton_5.Value==true
                app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Q4WhoisyourfavoriteoftheseartistsButtonGroup = uibuttongroup(app.UIFigure);
            app.Q4WhoisyourfavoriteoftheseartistsButtonGroup.Title = 'Q4 Who is your favorite of these artists?';
            app.LizzoButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.LizzoButton.Text = 'Lizzo';
            app.LizzoButton.Value = true;
            app.SteveAokiButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.SteveAokiButton.Text = 'Steve Aoki';
            app.LewisCapaldiButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.LewisCapaldiButton.Text = 'Lewis Capaldi';
            app.SiaButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.SiaButton.Text = 'Sia';
            app.ABoogieButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.ABoogieButton.Text = 'A Boogie';
            app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup = uibuttongroup(app.UIFigure);
            app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup.Title = 'Q5 Which would you rather do in your free time?';
            app.PilatesmeditationButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.PilatesmeditationButton.Text = 'Pilates & meditation';
            app.PilatesmeditationButton.Value = true;
            app.PlaysoccerButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.PlaysoccerButton.Text = 'Play soccer';
            app.GolfButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.GolfButton.Text = 'Golf';
            app.DraworpaintButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.DraworpaintButton.Text = 'Draw or paint';
            app.GooutButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.GooutButton.Text = 'Go out';
            app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup = uibuttongroup(app.UIFigure);
            app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup.Title = 'Q6 Which role do you have in your friend group?';
            app.MomfriendButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.MomfriendButton.Text = 'Mom friend';
            app.MomfriendButton.Value = true;
            app.ClassclownButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.ClassclownButton.Text = 'Class clown';
            app.CrazyuncleButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.CrazyuncleButton.Text = 'Crazy uncle';
            app.TheonepeoplegotoforadviceButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.TheonepeoplegotoforadviceButton.Text = 'The one people go to for advice';
            app.DadfriendButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.DadfriendButton.Text = 'Dad friend';
            app.Q7WhatisyourfavoritecolorButtonGroup = uibuttongroup(app.UIFigure);
            app.Q7WhatisyourfavoritecolorButtonGroup.Title = 'Q7 What is your favorite color?';
            app.GreenButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.GreenButton.Value = true;
            app.BlueButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.OrangeButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.OrangeButton.Text = 'Orange';
            app.YellowButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.YellowButton.Text = 'Yellow';
            app.RedButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.RedButton.Text = 'Red';
            app.Q8WhichpetwouldyouratherhaveButtonGroup = uibuttongroup(app.UIFigure);
            app.Q8WhichpetwouldyouratherhaveButtonGroup.Title = 'Q8 Which pet would you rather have?';
            app.CalicokittenButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.CalicokittenButton.Text = {'Calico kitten'; ''};
            app.CalicokittenButton.Value = true;
            app.AustralianshepherdpuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.AustralianshepherdpuppyButton.Text = 'Australian shepherd puppy ';
            app.GoldenretrieverpuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.GoldenretrieverpuppyButton.Text = 'Golden retriever puppy';
            app.BlackkittenButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.BlackkittenButton.Text = 'Black kitten';
            app.BeaglepuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.BeaglepuppyButton.Text = 'Beagle puppy ';
            app.Q8WhichpetwouldyouratherhaveButtonGroup_3 = uibuttongroup(app.UIFigure);
            app.Q8WhichpetwouldyouratherhaveButtonGroup_3.Title = 'Q9 If you weren’t studying CLPS, what else would you want to study at Brown?';
            app.LiteraryartsButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.LiteraryartsButton.Text = 'Literary arts ';
            app.LiteraryartsButton.Value = true;
            app.MCMButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.MCMButton.Text = 'MCM';
            app.HistoryButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.HistoryButton.Text = 'History';
            app.VISAButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.VISAButton.Text = 'VISA';
            app.APMAEconwithanentrepreneurshipcertificateButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.APMAEconwithanentrepreneurshipcertificateButton.Text = 'APMA Econ with an entrepreneurship certificate ';
            app.Q3WhatwouldyouridealfirstdatebeButtonGroup = uibuttongroup(app.UIFigure);
            app.Q3WhatwouldyouridealfirstdatebeButtonGroup.Title = 'Q3 What would your ideal first date be?';
            app.iceskatingButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.iceskatingButton.Text = 'ice skating';
            app.iceskatingButton.Value = true;
            app.goingforadriveButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.goingforadriveButton.Text = 'going for a drive';
            app.stayinginandplayinggamesButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.stayinginandplayinggamesButton.Text = 'staying in and playing games';
            app.fancydinnerdateButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.fancydinnerdateButton.Text = 'fancy dinner date';
            app.goingtoacarnivalButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.goingtoacarnivalButton.Text = 'going to a carnival';
            app.Q2WhatisyourfavoriteseasonButtonGroup = uibuttongroup(app.UIFigure);
            app.Q2WhatisyourfavoriteseasonButtonGroup.Title = 'Q2 What is your favorite season?';
            app.summerButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.summerButton.Text = 'summer';
            app.summerButton.Value = true;
            app.winterButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.winterButton.Text = 'winter';
            app.springButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.springButton.Text = 'spring';
            app.fallButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.fallButton.Text = 'fall';
            app.IlikethemallButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.IlikethemallButton.Text = 'I like them all!';
            app.Q1WhatisyourfavoritefoodButtonGroup = uibuttongroup(app.UIFigure);
            app.Q1WhatisyourfavoritefoodButtonGroup.Title = 'Q1 What is your favorite food?';
            app.fruitsandveggiesButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.fruitsandveggiesButton.Text = 'fruits and veggies';
            app.fruitsandveggiesButton.Value = true;
            app.fastfoodButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.fastfoodButton.Text = 'fast food';
            app.WhoneedsfoodwhenyouhavebeerButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.WhoneedsfoodwhenyouhavebeerButton.Text = 'Who needs food when you have beer?';
            app.pastaButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.pastaButton.Text = 'pasta!!';
            app.MexicanfoodButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.MexicanfoodButton.Text = 'Mexican food';
            app.SUBMITButton = uibutton(app.UIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.WhichOneDirectionMemberAreYouLabel = uilabel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouLabel.Text = 'Which One Direction Member Are You?';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_8323.JPG');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '73ef9ca68981d5688ce7673ec4001abb.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '68305cf6fa5c7a4b56bac69cd797e812.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'c45258f08ee609c62cb79d58ac394eba.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'cb1d109659d69e6bf0d3c9c9aa37934f.jpg');
            app.WhichOneDirectionMemberAreYouPanel_2 = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_2.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_2.Visible = 'off';
            app.TextArea_2 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_2);
            app.TextArea_2.Value = {''; 'ZAYN MALIK'};
            app.continueButton_4 = uibutton(app.WhichOneDirectionMemberAreYouPanel_2, 'push');
            app.continueButton_4.ButtonPushedFcn = createCallbackFcn(app, @continueButton_4Pushed, true);
            app.continueButton_4.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_3 = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_3.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_3.Visible = 'off';
            app.TextArea_3 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_3);
            app.TextArea_3.Value = {'LOUIS TOMLINSON'};
            app.continueButton_2 = uibutton(app.WhichOneDirectionMemberAreYouPanel_3, 'push');
            app.continueButton_2.ButtonPushedFcn = createCallbackFcn(app, @continueButton_2Pushed, true);
            app.continueButton_2.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_5 = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_5.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_5.Visible = 'off';
            app.TextArea_5 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_5);
            app.TextArea_5.Value = {''; 'NIALL HORAN'};
            app.continueButton_3 = uibutton(app.WhichOneDirectionMemberAreYouPanel_5, 'push');
            app.continueButton_3.ButtonPushedFcn = createCallbackFcn(app, @continueButton_3Pushed, true);
            app.continueButton_3.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_6 = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_6.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_6.Visible = 'off';
            app.TextArea_6 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_6);
            app.TextArea_6.Value = {'omg?!? you''re NOT a 1D member... you''re TAYLOR SWIFT'};
            app.continueButton_6 = uibutton(app.WhichOneDirectionMemberAreYouPanel_6, 'push');
            app.continueButton_6.ButtonPushedFcn = createCallbackFcn(app, @continueButton_6Pushed, true);
            app.continueButton_6.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_4 = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_4.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_4.Visible = 'off';
            app.TextArea_4 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_4);
            app.TextArea_4.Value = {''; 'LIAM PAYNE'};
            app.continueButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_4, 'push');
            app.continueButton.ButtonPushedFcn = createCallbackFcn(app, @continueButtonPushed, true);
            app.continueButton.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel.Visible = 'off';
            app.TextArea = uitextarea(app.WhichOneDirectionMemberAreYouPanel);
            app.TextArea.Value = {'HARRY STYLES'};
            app.continueButton_5 = uibutton(app.WhichOneDirectionMemberAreYouPanel, 'push');
            app.continueButton_5.ButtonPushedFcn = createCallbackFcn(app, @continueButton_5Pushed, true);
            app.continueButton_5.Text = 'continue';
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel = uipanel(app.UIFigure);
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Title = 'So you''re Louis Tomlinson...but what Louis Tomlinson song are you?';
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_2 = uibuttongroup(app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel);
            app.HowareyoufeelingButtonGroup_2.Title = 'How are you feeling?';
            app.HappyButton_2 = uiradiobutton(app.HowareyoufeelingButtonGroup_2);
            app.HappyButton_2.Text = 'Happy';
            app.HappyButton_2.Value = true;
            app.SadButton_2 = uiradiobutton(app.HowareyoufeelingButtonGroup_2);
            app.SadButton_2.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_2 = uibuttongroup(app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_2.Title = 'What''s your relationship status?';
            app.IminloveButton_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.IminloveButton_5.Text = 'I''m in love :)';
            app.IminloveButton_5.Value = true;
            app.Imheartbroken3Button_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.Imheartbroken3Button_5.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.SingleandchillingButton_5.Text = 'Single and chilling';
            app.ItscomplicatedButton_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.ItscomplicatedButton_5.Text = 'It''s complicated...';
            app.SubmitButton_2 = uibutton(app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel, 'push');
            app.SubmitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_2Pushed, true);
            app.SubmitButton_2.Text = 'Submit';
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel = uipanel(app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel);
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Title = 'So you''re Harry Styles...but what Harry Styles song are you?';
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup = uibuttongroup(app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel);
            app.HowareyoufeelingButtonGroup.Title = 'How are you feeling?';
            app.HappyButton = uiradiobutton(app.HowareyoufeelingButtonGroup);
            app.HappyButton.Text = 'Happy';
            app.HappyButton.Value = true;
            app.SadButton = uiradiobutton(app.HowareyoufeelingButtonGroup);
            app.SadButton.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup = uibuttongroup(app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup.Title = 'What''s your relationship status?';
            app.IminloveButton_6 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.IminloveButton_6.Text = 'I''m in love :)';
            app.IminloveButton_6.Value = true;
            app.Imheartbroken3Button_6 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.Imheartbroken3Button_6.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton_6 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.SingleandchillingButton_6.Text = 'Single and chilling';
            app.ItscomplicatedButton_6 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.ItscomplicatedButton_6.Text = 'It''s complicated...';
            app.SubmitButton = uibutton(app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel = uipanel(app.UIFigure);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Title = 'So you''re happy and in love...here''s your Harry Styles song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible = 'off';
            app.EditField = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel, 'text');
            app.EditField.Value = 'Sunflower Vol. 6';
            app.Image6_12 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.Image6_12.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.05.49 AM.png');
            app.Image6_13 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.Image6_13.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.05.49 AM.png');
            app.Hyperlink2_3 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.Hyperlink2_3.URL = 'https://open.spotify.com/track/6iYMfxznTBlcVOgRHab2W0?si=6ae3059c87134601';
            app.Hyperlink2_3.Text = 'click here to listen';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_9 = uipanel(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_9.Title = 'So you''re happy and heartbroken...here''s your Harry Styles song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_9.Visible = 'off';
            app.EditField_9 = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_9, 'text');
            app.EditField_9.Value = 'Caronlina';
            app.Image6_9 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_9);
            app.Image6_9.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.08.57 AM.png');
            app.Image6_10 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_9);
            app.Image6_10.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.08.57 AM.png');
            app.Image6_11 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_9);
            app.Image6_11.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.08.57 AM.png');
            app.Hyperlink2_2 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_9);
            app.Hyperlink2_2.URL = 'https://open.spotify.com/track/3Dwq1oiOFys6Sxr5SslmXw?si=66fe45c6d8e94911';
            app.Hyperlink2_2.Text = 'click here to listen';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_10 = uipanel(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_10.Title = 'So you''re happy and single...here''s your Harry Styles song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_10.Visible = 'off';
            app.EditField_10 = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_10, 'text');
            app.EditField_10.Value = 'Canyon Moon';
            app.Image6_14 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_10);
            app.Image6_14.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.14.54 AM.png');
            app.Image6_15 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_10);
            app.Image6_15.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.14.54 AM.png');
            app.Image6_16 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_10);
            app.Image6_16.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.14.54 AM.png');
            app.Hyperlink2_4 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_10);
            app.Hyperlink2_4.URL = 'https://open.spotify.com/track/3Dwq1oiOFys6Sxr5SslmXw?si=66fe45c6d8e94911';
            app.Hyperlink2_4.Text = 'click here to listen';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_11 = uipanel(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_11.Title = 'So you''re happy and complicated...here''s your Harry Styles song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_11.Visible = 'off';
            app.EditField_11 = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_11, 'text');
            app.EditField_11.Value = 'Satellite';
            app.Image6_17 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_11);
            app.Image6_17.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.19.13 AM.png');
            app.Image6_18 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_11);
            app.Image6_18.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.19.13 AM.png');
            app.Image6_19 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_11);
            app.Image6_19.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.19.13 AM.png');
            app.Hyperlink2_5 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_11);
            app.Hyperlink2_5.URL = 'https://open.spotify.com/track/0rzaRSujxA0bKyjJl6vHYq?si=c7e413df07aa408e';
            app.Hyperlink2_5.Text = 'click here to listen';
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel = uipanel(app.UIFigure);
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Title = 'So you''re Taylor Swift...but what Taylor Swift song are you?';
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_6 = uibuttongroup(app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel);
            app.HowareyoufeelingButtonGroup_6.Title = 'How are you feeling?';
            app.HappyButton_6 = uiradiobutton(app.HowareyoufeelingButtonGroup_6);
            app.HappyButton_6.Text = 'Happy';
            app.HappyButton_6.Value = true;
            app.SadButton_6 = uiradiobutton(app.HowareyoufeelingButtonGroup_6);
            app.SadButton_6.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_6 = uibuttongroup(app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_6.Title = 'What''s your relationship status?';
            app.IminloveButton_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.IminloveButton_4.Text = 'I''m in love :)';
            app.IminloveButton_4.Value = true;
            app.Imheartbroken3Button_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.Imheartbroken3Button_4.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.SingleandchillingButton_4.Text = 'Single and chilling';
            app.ItscomplicatedButton_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.ItscomplicatedButton_4.Text = 'It''s complicated...';
            app.SubmitButton_6 = uibutton(app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel, 'push');
            app.SubmitButton_6.Text = 'Submit';
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel = uipanel(app.UIFigure);
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Title = 'So you''re Zayn Malik...but what Zayn Malik song are you?';
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_3 = uibuttongroup(app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel);
            app.HowareyoufeelingButtonGroup_3.Title = 'How are you feeling?';
            app.HappyButton_3 = uiradiobutton(app.HowareyoufeelingButtonGroup_3);
            app.HappyButton_3.Text = 'Happy';
            app.HappyButton_3.Value = true;
            app.SadButton_3 = uiradiobutton(app.HowareyoufeelingButtonGroup_3);
            app.SadButton_3.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_3 = uibuttongroup(app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_3.Title = 'What''s your relationship status?';
            app.IminloveButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.IminloveButton.Text = 'I''m in love :)';
            app.IminloveButton.Value = true;
            app.Imheartbroken3Button = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.Imheartbroken3Button.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.SingleandchillingButton.Text = 'Single and chilling';
            app.ItscomplicatedButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.ItscomplicatedButton.Text = 'It''s complicated...';
            app.SubmitButton_3 = uibutton(app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel, 'push');
            app.SubmitButton_3.Text = 'Submit';
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel = uipanel(app.UIFigure);
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Title = 'So you''re Niall Horan...but what Niall Horan song are you?';
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_4 = uibuttongroup(app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel);
            app.HowareyoufeelingButtonGroup_4.Title = 'How are you feeling?';
            app.HappyButton_4 = uiradiobutton(app.HowareyoufeelingButtonGroup_4);
            app.HappyButton_4.Text = 'Happy';
            app.HappyButton_4.Value = true;
            app.SadButton_4 = uiradiobutton(app.HowareyoufeelingButtonGroup_4);
            app.SadButton_4.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_4 = uibuttongroup(app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_4.Title = 'What''s your relationship status?';
            app.IminloveButton_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.IminloveButton_2.Text = 'I''m in love :)';
            app.IminloveButton_2.Value = true;
            app.Imheartbroken3Button_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.Imheartbroken3Button_2.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.SingleandchillingButton_2.Text = 'Single and chilling';
            app.ItscomplicatedButton_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.ItscomplicatedButton_2.Text = 'It''s complicated...';
            app.SubmitButton_4 = uibutton(app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel, 'push');
            app.SubmitButton_4.Text = 'Submit';
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel = uipanel(app.UIFigure);
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Title = 'So you''re Liam Payne...but what Liam Payne song are you?';
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_5 = uibuttongroup(app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel);
            app.HowareyoufeelingButtonGroup_5.Title = 'How are you feeling?';
            app.HappyButton_5 = uiradiobutton(app.HowareyoufeelingButtonGroup_5);
            app.HappyButton_5.Text = 'Happy';
            app.HappyButton_5.Value = true;
            app.SadButton_5 = uiradiobutton(app.HowareyoufeelingButtonGroup_5);
            app.SadButton_5.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_5 = uibuttongroup(app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_5.Title = 'What''s your relationship status?';
            app.IminloveButton_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.IminloveButton_3.Text = 'I''m in love :)';
            app.IminloveButton_3.Value = true;
            app.Imheartbroken3Button_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.Imheartbroken3Button_3.Text = 'I''m heartbroken </3';
            app.SingleandchillingButton_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.SingleandchillingButton_3.Text = 'Single and chilling';
            app.ItscomplicatedButton_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.ItscomplicatedButton_3.Text = 'It''s complicated...';
            app.SubmitButton_5 = uibutton(app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel, 'push');
            app.SubmitButton_5.Text = 'Submit';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_2 = uipanel(app.UIFigure);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_2.Title = 'So you''re happy and in love...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_2.Visible = 'off';
            app.EditField_2 = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_2, 'text');
            app.EditField_2.Value = '    Always You';
            app.Image6_2 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_2);
            app.Image6_2.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.35.30 AM.png');
            app.Hyperlink = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_2);
            app.Hyperlink.URL = 'https://open.spotify.com/track/5KcT9rJDqQJUSFF3O9wPJ5?si=9289be20e6a14c6e';
            app.Hyperlink.Text = 'click to listen!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_3 = uipanel(app.UIFigure);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_3.Title = 'So you''re happy and heartbroken...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_3.Visible = 'off';
            app.EditField_3 = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_3, 'text');
            app.EditField_3.Value = '       Headline';
            app.Image6_3 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_3);
            app.Image6_3.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.45.55 AM.png');
            app.Hyperlink_2 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_3);
            app.Hyperlink_2.URL = 'https://open.spotify.com/track/1xRbWod9cdwCF0rF04LjDi?si=be4aeeaf81ba4d96';
            app.Hyperlink_2.Text = 'click to listen!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_4 = uipanel(app.UIFigure);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_4.Title = 'So you''re happy and single...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_4.Visible = 'off';
            app.EditField_4 = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_4, 'text');
            app.EditField_4.Value = 'Silver Tounges';
            app.Image6_4 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_4);
            app.Image6_4.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.49.39 AM.png');
            app.Hyperlink_3 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_4);
            app.Hyperlink_3.URL = 'https://open.spotify.com/track/29z96EFvG0L1wcj5kkIHzk?si=a3ec697c3c1f4b07d9cdwCF0rF04LjDi?si=be4aeeaf81ba4d96';
            app.Hyperlink_3.Text = 'click to listen!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_5 = uipanel(app.UIFigure);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_5.Title = 'So you''re happy and complicated...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_5.Visible = 'off';
            app.EditField_5 = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_5, 'text');
            app.EditField_5.Value = 'Don''t Let It Break Your Heart';
            app.Image6_5 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_5);
            app.Image6_5.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.52.16 AM.png');
            app.Hyperlink_4 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_5);
            app.Hyperlink_4.URL = 'https://open.spotify.com/track/4nbQRLAPq202lsQsv9FNwK?si=b566e8c59bb04482';
            app.Hyperlink_4.Text = 'click to listen!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_6 = uipanel(app.UIFigure);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_6.Title = 'So you''re sad and in love...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_6.Visible = 'off';
            app.EditField_6 = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_6, 'text');
            app.EditField_6.Value = 'That''s The Way Love Goes';
            app.Image6_6 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_6);
            app.Image6_6.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.54.54 AM.png');
            app.Hyperlink_5 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_6);
            app.Hyperlink_5.URL = 'https://open.spotify.com/track/4o4f31HSyquyh0ANKJsgnb?si=4ce398e0529043b2';
            app.Hyperlink_5.Text = 'click to listen!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_7 = uipanel(app.UIFigure);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_7.Title = 'So you''re sad and complicated...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_7.Visible = 'off';
            app.EditField_7 = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_7, 'text');
            app.EditField_7.Value = 'Chicago';
            app.Image6_7 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_7);
            app.Image6_7.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.04.23 AM.png');
            app.Hyperlink_6 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_7);
            app.Hyperlink_6.URL = 'https://open.spotify.com/track/6ypAO1oxZUBVeHtdFOi3Cj?si=e8918d5ad2d3413b';
            app.Hyperlink_6.Text = 'click to listen!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_8 = uipanel(app.UIFigure);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_8.Title = 'So you''re sad and single...here''s your Louis Tomlin song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_8.Visible = 'off';
            app.EditField_8 = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_8, 'text');
            app.EditField_8.Value = 'Two of Us';
            app.Image6_8 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_8);
            app.Image6_8.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.01.15 AM.png');
            app.Hyperlink_7 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel_8);
            app.Hyperlink_7.URL = 'https://open.spotify.com/track/1eqwjXdMTed8Pz3PLNcHe9?si=9a23bed036d140d9';
            app.Hyperlink_7.Text = 'click to listen!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = design_page_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end