classdef tryingtabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        RESETButton                     matlab.ui.control.Button
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        WhichOneDirectionMemberAreYouLabel  matlab.ui.control.Label
        SUBMITButton                    matlab.ui.control.Button
        Q1WhatisyourfavoritefoodButtonGroup  matlab.ui.container.ButtonGroup
        MexicanfoodButton               matlab.ui.control.RadioButton
        pastaButton                     matlab.ui.control.RadioButton
        WhoneedsfoodwhenyouhavebeerButton  matlab.ui.control.RadioButton
        fastfoodButton                  matlab.ui.control.RadioButton
        fruitsandveggiesButton          matlab.ui.control.RadioButton
        Q2WhatisyourfavoriteseasonButtonGroup  matlab.ui.container.ButtonGroup
        IlikethemallButton              matlab.ui.control.RadioButton
        fallButton                      matlab.ui.control.RadioButton
        springButton                    matlab.ui.control.RadioButton
        winterButton                    matlab.ui.control.RadioButton
        summerButton                    matlab.ui.control.RadioButton
        Q3WhatwouldyouridealfirstdatebeButtonGroup  matlab.ui.container.ButtonGroup
        goingtoacarnivalButton          matlab.ui.control.RadioButton
        fancydinnerdateButton           matlab.ui.control.RadioButton
        stayinginandplayinggamesButton  matlab.ui.control.RadioButton
        goingforadriveButton            matlab.ui.control.RadioButton
        iceskatingButton                matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup_2  matlab.ui.container.ButtonGroup
        APMAEconwithanentrepreneurshipcertificateButton  matlab.ui.control.RadioButton
        VISAButton                      matlab.ui.control.RadioButton
        HistoryButton                   matlab.ui.control.RadioButton
        MCMButton                       matlab.ui.control.RadioButton
        LiteraryartsButton              matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup  matlab.ui.container.ButtonGroup
        BeaglepuppyButton               matlab.ui.control.RadioButton
        BlackkittenButton               matlab.ui.control.RadioButton
        GoldenretrieverpuppyButton      matlab.ui.control.RadioButton
        AustralianshepherdpuppyButton   matlab.ui.control.RadioButton
        CalicokittenButton              matlab.ui.control.RadioButton
        Q7WhatisyourfavoritecolorButtonGroup  matlab.ui.container.ButtonGroup
        RedButton                       matlab.ui.control.RadioButton
        YellowButton                    matlab.ui.control.RadioButton
        OrangeButton                    matlab.ui.control.RadioButton
        BlueButton                      matlab.ui.control.RadioButton
        GreenButton                     matlab.ui.control.RadioButton
        Q6WhichroledoyouhaveinyourfriendgroupButtonGroup  matlab.ui.container.ButtonGroup
        DadfriendButton                 matlab.ui.control.RadioButton
        TheonepeoplegotoforadviceButton  matlab.ui.control.RadioButton
        CrazyuncleButton                matlab.ui.control.RadioButton
        ClassclownButton                matlab.ui.control.RadioButton
        MomfriendButton                 matlab.ui.control.RadioButton
        Q5WhichwouldyouratherdoinyourfreetimeButtonGroup  matlab.ui.container.ButtonGroup
        GooutButton                     matlab.ui.control.RadioButton
        DraworpaintButton               matlab.ui.control.RadioButton
        GolfButton                      matlab.ui.control.RadioButton
        PlaysoccerButton                matlab.ui.control.RadioButton
        PilatesmeditationButton         matlab.ui.control.RadioButton
        Q4WhoisyourfavoriteoftheseartistsButtonGroup  matlab.ui.container.ButtonGroup
        ABoogieButton                   matlab.ui.control.RadioButton
        SiaButton                       matlab.ui.control.RadioButton
        LewisCapaldiButton              matlab.ui.control.RadioButton
        SteveAokiButton                 matlab.ui.control.RadioButton
        LizzoButton                     matlab.ui.control.RadioButton
        FirstQuizAnswersTab             matlab.ui.container.Tab
        WhichOneDirectionMemberAreYouPanel_louis  matlab.ui.container.Panel
        continuelouisButton             matlab.ui.control.Button
        TextArea_5                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_zayn  matlab.ui.container.Panel
        continueButton_zayn             matlab.ui.control.Button
        TextArea_6                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_Niall  matlab.ui.container.Panel
        continueniallButton             matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_Harry  matlab.ui.container.Panel
        continueharryButton             matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_Taylor  matlab.ui.container.Panel
        continuetaylorButton            matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_Liam  matlab.ui.container.Panel
        continueliamButton              matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        soyourebutTab                   matlab.ui.container.Tab
        SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel  matlab.ui.container.Panel
        SubmitTaylorButton              matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_2  matlab.ui.container.ButtonGroup
        ItscomplicatedTaylorButton      matlab.ui.control.RadioButton
        SingleandchillingTaylorButton   matlab.ui.control.RadioButton
        ImheartbrokenTaylorButton       matlab.ui.control.RadioButton
        IminloveTaylorButton            matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_2   matlab.ui.container.ButtonGroup
        SadTaylorButton                 matlab.ui.control.RadioButton
        HappyTaylorButton               matlab.ui.control.RadioButton
        SoyoureNiallHoranbutwhatNiallHoransongareyouPanel  matlab.ui.container.Panel
        SubmitNiallButton               matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_3  matlab.ui.container.ButtonGroup
        ItscomplicatedNiallButton       matlab.ui.control.RadioButton
        SingleandchillingNiallButton    matlab.ui.control.RadioButton
        Imheartbroken3NiallButton       matlab.ui.control.RadioButton
        IminloveNiallButton             matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_3   matlab.ui.container.ButtonGroup
        SadNiallButton                  matlab.ui.control.RadioButton
        HappyNiallButton                matlab.ui.control.RadioButton
        SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel  matlab.ui.container.Panel
        SubmitLiamButton                matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_6  matlab.ui.container.ButtonGroup
        ItscomplicatedLiamButton        matlab.ui.control.RadioButton
        SingleandchillingLiamButton     matlab.ui.control.RadioButton
        Imheartbroken3LiamButton        matlab.ui.control.RadioButton
        IminloveLiamButton              matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_6   matlab.ui.container.ButtonGroup
        HornyLiamButton                 matlab.ui.control.RadioButton
        HappyLiamButton                 matlab.ui.control.RadioButton
        SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel  matlab.ui.container.Panel
        SubmitZaynButton                matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_4  matlab.ui.container.ButtonGroup
        ItscomplicatedZaynButton        matlab.ui.control.RadioButton
        SingleandchillingZaynButton     matlab.ui.control.RadioButton
        Imheartbroken3ZaynButton        matlab.ui.control.RadioButton
        IminloveZaynButton              matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_4   matlab.ui.container.ButtonGroup
        SadZaynButton                   matlab.ui.control.RadioButton
        HappyZaynButton                 matlab.ui.control.RadioButton
        SoyoureHarryStylesbutwhatHarryStylessongareyouPanel  matlab.ui.container.Panel
        SubmitHarryButton               matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_5  matlab.ui.container.ButtonGroup
        ItscomplicatedHarryButton       matlab.ui.control.RadioButton
        SingleandchillingHarryButton    matlab.ui.control.RadioButton
        Imheartbroken3HarryButton       matlab.ui.control.RadioButton
        IminloveHarryButton             matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_5   matlab.ui.container.ButtonGroup
        SadHarryButton                  matlab.ui.control.RadioButton
        HappyHarryButton                matlab.ui.control.RadioButton
        SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel  matlab.ui.container.Panel
        SubmitLouisButton               matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup  matlab.ui.container.ButtonGroup
        ItscomplicatedLouisButton       matlab.ui.control.RadioButton
        SingleandchillingLouisButton    matlab.ui.control.RadioButton
        Imheartbroken3LouisButton       matlab.ui.control.RadioButton
        IminloveLouisButton             matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup     matlab.ui.container.ButtonGroup
        SadLouisButton                  matlab.ui.control.RadioButton
        HappyLouisButton                matlab.ui.control.RadioButton
        harryanswersTab                 matlab.ui.container.Tab
        soyourhappyandsingleHarryPanel  matlab.ui.container.Panel
        Hyperlink2_12                   matlab.ui.control.Hyperlink
        Image6_35                       matlab.ui.control.Image
        EditField_19                    matlab.ui.control.EditField
        soyoursadandsingleHarryPanel    matlab.ui.container.Panel
        Hyperlink2_7                    matlab.ui.control.Hyperlink
        Image6_23                       matlab.ui.control.Image
        your                            matlab.ui.control.Image
        EditField_14                    matlab.ui.control.EditField
        soyourhappyanditscomplicatedHarryPanel  matlab.ui.container.Panel
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Image6_12                       matlab.ui.control.Image
        Image6_11                       matlab.ui.control.Image
        Image6_10                       matlab.ui.control.Image
        EditField_9                     matlab.ui.control.EditField
        soyoursadandheartbrokenHarryPanel  matlab.ui.container.Panel
        Hyperlink2_6                    matlab.ui.control.Hyperlink
        Image6_22                       matlab.ui.control.Image
        Image6_21                       matlab.ui.control.Image
        EditField_13                    matlab.ui.control.EditField
        soyoursadandinloveHarryPanel    matlab.ui.container.Panel
        Hyperlink2_5                    matlab.ui.control.Hyperlink
        Image6_20                       matlab.ui.control.Image
        Image6_19                       matlab.ui.control.Image
        EditField_12                    matlab.ui.control.EditField
        soyourehappyandheartbrokenHarryPanel  matlab.ui.container.Panel
        Image6_16                       matlab.ui.control.Image
        Image6_18                       matlab.ui.control.Image
        EditField_11                    matlab.ui.control.EditField
        soyoursadanditscomplicatedHarryPanel_3  matlab.ui.container.Panel
        Hyperlink2_11                   matlab.ui.control.Hyperlink
        Image6_32                       matlab.ui.control.Image
        Image6_31                       matlab.ui.control.Image
        EditField_18                    matlab.ui.control.EditField
        soyourehappyandinloveheresyourHarrysongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink2                      matlab.ui.control.Hyperlink
        Image6_2                        matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        EditField                       matlab.ui.control.EditField
        louisanswersTab                 matlab.ui.container.Tab
        soyourehappyandinloveheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink                       matlab.ui.control.Hyperlink
        Image6_3                        matlab.ui.control.Image
        EditField_2                     matlab.ui.control.EditField
        soyouresadandheartbrokenheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_6                     matlab.ui.control.Hyperlink
        Image6_8                        matlab.ui.control.Image
        EditField_7                     matlab.ui.control.EditField
        soyouresadandsingleheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_5                     matlab.ui.control.Hyperlink
        Image6_7                        matlab.ui.control.Image
        EditField_6                     matlab.ui.control.EditField
        soyouresadandinloveheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_8                     matlab.ui.control.Hyperlink
        Image6_36                       matlab.ui.control.Image
        EditField_20                    matlab.ui.control.EditField
        soyouresadandcomplicatedheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_7                     matlab.ui.control.Hyperlink
        Image6_9                        matlab.ui.control.Image
        EditField_8                     matlab.ui.control.EditField
        soyourehappyandsingleheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Image6_5                        matlab.ui.control.Image
        EditField_4                     matlab.ui.control.EditField
        soyourehappyandcomplicatedheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_4                     matlab.ui.control.Hyperlink
        Image6_6                        matlab.ui.control.Image
        EditField_5                     matlab.ui.control.EditField
        soyourehappyandheartbrokenheresyourLouissongreccomendationPanel  matlab.ui.container.Panel
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Image6_4                        matlab.ui.control.Image
        EditField_3                     matlab.ui.control.EditField
        niallanswersTab                 matlab.ui.container.Tab
        soyourehappyandheartbrokenNiallPanel  matlab.ui.container.Panel
        Hyperlink_16                    matlab.ui.control.Hyperlink
        Image6_44                       matlab.ui.control.Image
        EditField_28                    matlab.ui.control.EditField
        soyourehappyandinloveNiallPanel  matlab.ui.container.Panel
        Hyperlink_15                    matlab.ui.control.Hyperlink
        Image6_43                       matlab.ui.control.Image
        EditField_27                    matlab.ui.control.EditField
        soyouresadandheartbrokenNiallPanel  matlab.ui.container.Panel
        Hyperlink_14                    matlab.ui.control.Hyperlink
        Image6_42                       matlab.ui.control.Image
        EditField_26                    matlab.ui.control.EditField
        soyouresadandsingleNiallPanel   matlab.ui.container.Panel
        Hyperlink_13                    matlab.ui.control.Hyperlink
        Image6_41                       matlab.ui.control.Image
        EditField_25                    matlab.ui.control.EditField
        soyouresadandinloveNiallPanel   matlab.ui.container.Panel
        Hyperlink_12                    matlab.ui.control.Hyperlink
        Image6_40                       matlab.ui.control.Image
        EditField_24                    matlab.ui.control.EditField
        soyouresadandcomplicatedNiallPanel  matlab.ui.container.Panel
        Hyperlink_11                    matlab.ui.control.Hyperlink
        Image6_39                       matlab.ui.control.Image
        EditField_23                    matlab.ui.control.EditField
        soyourehappyandsingleNiallPanel  matlab.ui.container.Panel
        Hyperlink_10                    matlab.ui.control.Hyperlink
        Image6_38                       matlab.ui.control.Image
        EditField_22                    matlab.ui.control.EditField
        soyourehappyandcomplicatedNiallPanel  matlab.ui.container.Panel
        Hyperlink_9                     matlab.ui.control.Hyperlink
        Image6_37                       matlab.ui.control.Image
        EditField_21                    matlab.ui.control.EditField
        zaynanswersTab                  matlab.ui.container.Tab
        SoyouresadandcomplicatedZaynPanel  matlab.ui.container.Panel
        Hyperlink_24                    matlab.ui.control.Hyperlink
        Image6_52                       matlab.ui.control.Image
        EditField_36                    matlab.ui.control.EditField
        SoyouresadandheartbrokenZaynPanel  matlab.ui.container.Panel
        Hyperlink_23                    matlab.ui.control.Hyperlink
        Image6_51                       matlab.ui.control.Image
        EditField_35                    matlab.ui.control.EditField
        SoyouresadandsingleZaynPanel    matlab.ui.container.Panel
        Hyperlink_22                    matlab.ui.control.Hyperlink
        Image6_50                       matlab.ui.control.Image
        EditField_34                    matlab.ui.control.EditField
        SoyouresadandinloveZaynPanel    matlab.ui.container.Panel
        Hyperlink_21                    matlab.ui.control.Hyperlink
        Image6_49                       matlab.ui.control.Image
        EditField_33                    matlab.ui.control.EditField
        SoyourehappyandcomplicatedZaynPanel  matlab.ui.container.Panel
        Hyperlink_20                    matlab.ui.control.Hyperlink
        Image6_48                       matlab.ui.control.Image
        EditField_32                    matlab.ui.control.EditField
        SoyourehappyandheartbrokenZaynPanel  matlab.ui.container.Panel
        Hyperlink_19                    matlab.ui.control.Hyperlink
        Image6_47                       matlab.ui.control.Image
        EditField_31                    matlab.ui.control.EditField
        SoyourehappyandsingleZaynPanel  matlab.ui.container.Panel
        Hyperlink_18                    matlab.ui.control.Hyperlink
        Image6_46                       matlab.ui.control.Image
        EditField_30                    matlab.ui.control.EditField
        SoyourehappyandinloveZaynPanel  matlab.ui.container.Panel
        Hyperlink_17                    matlab.ui.control.Hyperlink
        Image6_45                       matlab.ui.control.Image
        EditField_29                    matlab.ui.control.EditField
        liamanswersTab                  matlab.ui.container.Tab
        soyourehornyandinloveLiamPanel  matlab.ui.container.Panel
        stripthatdowngif                matlab.ui.control.Button
        Hyperlink_29                    matlab.ui.control.Hyperlink
        EditField_41                    matlab.ui.control.EditField
        SoyourehornyandcomplicatedLiamPanel  matlab.ui.container.Panel
        Hyperlink_32                    matlab.ui.control.Hyperlink
        Image6_60                       matlab.ui.control.Image
        EditField_44                    matlab.ui.control.EditField
        SoyourehornyandsingleLiamPanel  matlab.ui.container.Panel
        Hyperlink_31                    matlab.ui.control.Hyperlink
        Image6_59                       matlab.ui.control.Image
        EditField_43                    matlab.ui.control.EditField
        SoyourehornyandheartbrokenLiamPanel  matlab.ui.container.Panel
        Hyperlink_30                    matlab.ui.control.Hyperlink
        Image6_58                       matlab.ui.control.Image
        EditField_42                    matlab.ui.control.EditField
        SoyourehappyandcomplicatedLiamPanel  matlab.ui.container.Panel
        Hyperlink_28                    matlab.ui.control.Hyperlink
        Image6_56                       matlab.ui.control.Image
        EditField_40                    matlab.ui.control.EditField
        SoyourehappyandsingleLiamPanel  matlab.ui.container.Panel
        Hyperlink_27                    matlab.ui.control.Hyperlink
        Image6_55                       matlab.ui.control.Image
        EditField_39                    matlab.ui.control.EditField
        SoyourehappyandheartbrokenLiamPanel  matlab.ui.container.Panel
        Hyperlink_26                    matlab.ui.control.Hyperlink
        Image6_54                       matlab.ui.control.Image
        EditField_38                    matlab.ui.control.EditField
        soyourehappyandinloveLiamPanel  matlab.ui.container.Panel
        Hyperlink_25                    matlab.ui.control.Hyperlink
        Image6_53                       matlab.ui.control.Image
        EditField_37                    matlab.ui.control.EditField
        tayloranswersTab                matlab.ui.container.Tab
        soyouresadandcomplicatedTaylorPanel  matlab.ui.container.Panel
        Hyperlink_40                    matlab.ui.control.Hyperlink
        Image6_68                       matlab.ui.control.Image
        EditField_52                    matlab.ui.control.EditField
        soyouresadandsingleTaylorPanel  matlab.ui.container.Panel
        Hyperlink_39                    matlab.ui.control.Hyperlink
        Image6_67                       matlab.ui.control.Image
        EditField_51                    matlab.ui.control.EditField
        soyouresadandheartbrokenTaylorPanel  matlab.ui.container.Panel
        Hyperlink_38                    matlab.ui.control.Hyperlink
        Image6_66                       matlab.ui.control.Image
        EditField_50                    matlab.ui.control.EditField
        soyouresadandinloveTaylorPanel  matlab.ui.container.Panel
        Hyperlink_37                    matlab.ui.control.Hyperlink
        Image6_65                       matlab.ui.control.Image
        EditField_49                    matlab.ui.control.EditField
        soyourehappyandcomplicatedTaylorPanel  matlab.ui.container.Panel
        Hyperlink_36                    matlab.ui.control.Hyperlink
        Image6_64                       matlab.ui.control.Image
        EditField_48                    matlab.ui.control.EditField
        soyourehappyandheartbrokenTaylorPanel  matlab.ui.container.Panel
        Hyperlink_35                    matlab.ui.control.Hyperlink
        Image6_63                       matlab.ui.control.Image
        EditField_47                    matlab.ui.control.EditField
        soyourehappyandsingleTaylorPanel  matlab.ui.container.Panel
        Hyperlink_34                    matlab.ui.control.Hyperlink
        Image6_62                       matlab.ui.control.Image
        EditField_46                    matlab.ui.control.EditField
        soyourehappyandinloveTaylorPanel  matlab.ui.container.Panel
        Hyperlink_33                    matlab.ui.control.Hyperlink
        Image6_61                       matlab.ui.control.Image
        EditField_45                    matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
                  
score_harry = 0; %% INITIALIZE SCORES AND SET ALL TO 0
score_louis = 0;
score_niall = 0;
score_zayn = 0;
score_liam = 0;
app.WhichOneDirectionMemberAreYouPanel_Harry.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_zayn.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_louis.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_Liam.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_Niall.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_Taylor.Visible = 'off';
app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible='off';
app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Visible='off';
app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Visible='off';
app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Visible='off';
app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible='off';
app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Visible='off';

app.TabGroup.SelectedTab = app.FirstQuizAnswersTab;

%%QUESTION 1
if app.fruitsandveggiesButton.Value == true
      score_harry = score_harry + 1;
elseif app.fastfoodButton.Value ==true
      score_louis = score_louis + 1;
elseif app.WhoneedsfoodwhenyouhavebeerButton.Value ==true
      score_niall = score_niall + 1;
elseif app.pastaButton.Value ==true
      score_zayn = score_zayn + 1;
else app.MexicanfoodButton.Value ==true;
      score_liam = score_liam + 1;
end

%%QUESTION 2
if app.summerButton.Value ==true
      score_harry = score_harry + 1;
elseif app.winterButton.Value ==true
      score_louis = score_louis + 1;
elseif app.springButton.Value ==true
      score_niall = score_niall + 1;
elseif app.fallButton.Value ==true
      score_zayn = score_zayn + 1;
else app.IlikethemallButton.Value ==true;
      score_liam = score_liam + 1;
end

%%QUESTION 3
if app.iceskatingButton.Value ==true
      score_harry = score_harry + 1;
elseif app.goingforadriveButton.Value ==true
      score_louis = score_louis + 1;
elseif app.stayinginandplayinggamesButton.Value ==true
      score_niall = score_niall + 1;
elseif app.fancydinnerdateButton.Value ==true
      score_zayn = score_zayn + 1;
else app.goingtoacarnivalButton.Value ==true;
      score_liam = score_liam + 1;
end

%%QUESTION 4
if app.LizzoButton.Value == true
     score_harry = score_harry + 1;
elseif app.SteveAokiButton.Value == true
     score_louis = score_louis + 1;
elseif app.LewisCapaldiButton.Value == true
     score_niall = score_niall + 1;
elseif app.SiaButton.Value== true
     score_zayn = score_zayn + 1;
else app.ABoogieButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 5
if app.PilatesmeditationButton.Value == true
     score_harry = score_harry + 1;
elseif app.PlaysoccerButton.Value == true
     score_louis = score_louis + 1;
elseif app.GolfButton.Value == true
     score_niall = score_niall + 1;
elseif app.DraworpaintButton.Value == true
     score_zayn = score_zayn + 1;
else app.GooutButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 6
if app.MomfriendButton.Value == true
     score_harry = score_harry + 1;
elseif app.ClassclownButton.Value == true
     score_louis = score_louis + 1;
elseif app.CrazyuncleButton.Value == true
     score_niall = score_niall + 1;
elseif app.TheonepeoplegotoforadviceButton.Value == true
     score_zayn = score_zayn + 1;
else app.DadfriendButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 7
if app.GreenButton.Value == true
   score_harry = score_harry + 1;
elseif app.BlueButton.Value == true
   score_louis = score_louis + 1;
elseif app.OrangeButton.Value == true
   score_niall = score_niall + 1;
elseif app.YellowButton.Value == true
   score_zayn = score_zayn + 1;
else app.RedButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 8
if app.CalicokittenButton.Value == true
   score_harry = score_harry + 1;
elseif app.AustralianshepherdpuppyButton.Value == true
   score_louis = score_louis + 1;
elseif app.GoldenretrieverpuppyButton.Value == true
   score_niall = score_niall + 1;
elseif app.BlackkittenButton.Value == true
   score_zayn = score_zayn + 1;
else app.BeaglepuppyButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 9
if app.LiteraryartsButton.Value == true
   score_harry = score_harry + 1;
elseif app.MCMButton.Value == true
   score_louis = score_louis + 1;
elseif app.HistoryButton.Value == true
   score_niall = score_niall + 1;
elseif app.VISAButton.Value == true
   score_zayn = score_zayn + 1;
else app.APMAEconwithanentrepreneurshipcertificateButton.Value == true;
   score_liam = score_liam + 1;
end

%%RESULTS SECTION
if score_harry > score_louis & score_harry > score_niall & score_harry > score_zayn & score_harry > score_liam;
   disp('Harry Styles!');
   app.WhichOneDirectionMemberAreYouPanel_Harry.Visible = 'on';
elseif score_louis > score_harry & score_louis > score_niall & score_louis > score_zayn & score_louis > score_liam;
   disp('Louis Tomlinson!');
   app.WhichOneDirectionMemberAreYouPanel_louis.Visible = 'on';
elseif score_niall > score_harry & score_niall > score_louis & score_niall > score_zayn & score_niall > score_liam;
   disp('Niall Horan!');
   app.WhichOneDirectionMemberAreYouPanel_Niall.Visible = 'on';
elseif score_zayn > score_harry & score_zayn > score_niall & score_zayn > score_liam & score_zayn > score_louis;
   disp('Zayn Malik!');
   app.WhichOneDirectionMemberAreYouPanel_zayn.Visible = 'on';
elseif score_liam > score_harry & score_liam > score_niall & score_liam > score_zayn & score_liam > score_louis;
   disp('Liam Payne!');
   app.WhichOneDirectionMemberAreYouPanel_Liam.Visible = 'on';
else score_harry==score_louis | score_harry==score_niall|score_harry==score_zayn|score_harry==score_liam|score_louis==score_niall|score_louis==score_zayn|score_louis==score_liam|score_niall==score_zayn|score_niall==score_liam|score_zayn==score_liam;
    disp('Taylor Swift')
    app.WhichOneDirectionMemberAreYouPanel_Taylor.Visible = 'on';
end
        end

        % Button pushed function: continueharryButton
        function continueharryButtonPushed(app, event)
app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible='on';
app.TabGroup.SelectedTab = app.soyourebutTab;
        end

        % Button pushed function: continueliamButton
        function continueliamButtonPushed(app, event)
  app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Visible='on';
app.TabGroup.SelectedTab = app.soyourebutTab;
        end

        % Button pushed function: continuetaylorButton
        function continuetaylorButtonPushed(app, event)
           app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Visible='on';
app.TabGroup.SelectedTab = app.soyourebutTab;
        end

        % Button pushed function: continueniallButton
        function continueniallButtonPushed(app, event)
 app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Visible='on';
app.TabGroup.SelectedTab = app.soyourebutTab;
        end

        % Button pushed function: SubmitHarryButton
        function SubmitHarryButtonPushed(app, event)

%app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible='off';
%app.soyourhappyandheartbrokenHarryPanel.Visible='off';
%app.soyourhappyandsingleHarryPanel.Visible='off';
%app.soyourhappyanditscomplicatedHarryPanel.Visible='off';
%app.soyoursadandinloveHarryPanel.Visible='off';
%app.soyoursadandheartbrokenHarryPanel.Visible='off';
%app.soyoursadandsingleHarryPanel.Visible='off';
%app.soyourhappyandsingleHarryPanel.Visible='off';

            if app.HappyHarryButton.Value == true && app.IminloveHarryButton.Value == true
                app.TabGroup.SelectedTab = app.harryanswersTab;
                app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible='on';
                [y, Fs] = audioread('Sunflower.mov');
                sound(y, Fs);
            end 
            if app.HappyHarryButton.Value == true & app.Imheartbroken3HarryButton.Value ==true
                app.TabGroup.SelectedTab = app.harryanswersTab;
                app.soyourehappyandheartbrokenHarryPanel.Visible='on';
                [y, Fs] = audioread('carolina.mov');
                sound(y, Fs);
            end
            if app.HappyHarryButton.Value == true & app.SingleandchillingHarryButton.Value == true
                app.TabGroup.SelectedTab = app.harryanswersTab;
                app.soyourhappyandsingleHarryPanel.Visible='on';
                [y, Fs] = audioread('canyonmoon.mov');
                sound(y, Fs);
            end
            if app.HappyHarryButton.Value == true & app.ItscomplicatedHarryButton.Value ==true
                app.TabGroup.SelectedTab = app.harryanswersTab;
                app.soyourhappyanditscomplicatedHarryPanel.Visible='on';
                [y, Fs] = audioread('Satellite.mov');
                sound(y, Fs);
            end 
            if app.SadHarryButton.Value == true & app.IminloveHarryButton.Value == true
                app.TabGroup.SelectedTab = app.harryanswersTab;
                app.soyoursadandinloveHarryPanel.Visible='on';
                [y, Fs] = audioread('loml.mov');
                sound(y, Fs);
            end
            if app.SadHarryButton.Value == true & app.Imheartbroken3HarryButton.Value == true
                app.TabGroup.SelectedTab = app.harryanswersTab;
                app.soyoursadandheartbrokenHarryPanel.Visible='on';
                [y, Fs] = audioread('Falling.mov');
                sound(y, Fs);
            end 
            if app.SadHarryButton.Value == true & app.SingleandchillingHarryButton.Value == true
                app.TabGroup.SelectedTab = app.harryanswersTab;
                app.soyoursadandsingleHarryPanel.Visible='on';
                [y, Fs] = audioread('fineline.mov');
                sound(y, Fs);
            end 
            if app.SadHarryButton.Value == true & app.ItscomplicatedHarryButton.Value == true
                app.TabGroup.SelectedTab = app.harryanswersTab;
                app.soyoursadanditscomplicatedHarryPanel_3.Visible='on';
                [y, Fs] = audioread('girlcrush.mov');
                sound(y, Fs);
            end  

        end

        % Button pushed function: continuelouisButton
        function continuelouisButtonPushed(app, event)
app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible='on';
app.TabGroup.SelectedTab = app.soyourebutTab;
        end

        % Button pushed function: continueButton_zayn
        function continueButton_zaynPushed(app, event)
app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Visible='on';
app.TabGroup.SelectedTab = app.soyourebutTab;
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            
        end

        % Button pushed function: SubmitLouisButton
        function SubmitLouisButtonPushed(app, event)
            
             if app.HappyLouisButton.Value == true && app.IminloveLouisButton.Value == true
                app.TabGroup.SelectedTab = app.louisanswersTab;
                app.soyourehappyandinloveheresyourLouissongreccomendationPanel.Visible='on';
                [y, Fs] = audioread('kmm.mov');
                sound(y, Fs);
            end 
            if app.HappyLouisButton.Value == true & app.Imheartbroken3LouisButton.Value ==true
                app.TabGroup.SelectedTab = app.louisanswersTab;
                app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel.Visible='on';
                [y, Fs] = audioread('alwaysyou.mov');
                sound(y, Fs);
            end
            if app.HappyLouisButton.Value == true & app.SingleandchillingLouisButton.Value == true
                app.TabGroup.SelectedTab = app.louisanswersTab;
                app.soyourehappyandsingleheresyourLouissongreccomendationPanel.Visible='on';
                [y, Fs] = audioread('silvertongues.mov');
                sound(y, Fs);
            end
            if app.HappyLouisButton.Value == true & app.ItscomplicatedLouisButton.Value ==true
                app.TabGroup.SelectedTab = app.louisanswersTab;
                app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel.Visible='on';
                [y, Fs] = audioread('defenceless.mov');
                sound(y, Fs);
            end 
            if app.SadLouisButton.Value == true & app.IminloveLouisButton.Value == true
                app.TabGroup.SelectedTab = app.louisanswersTab;
                app.soyouresadandinloveheresyourLouissongreccomendationPanel.Visible='on';
                [y, Fs] = audioread('habit.mov');
                sound(y, Fs);
            end
            if app.SadLouisButton.Value == true & app.Imheartbroken3LouisButton.Value == true
                app.TabGroup.SelectedTab = app.louisanswersTab;
                app.soyouresadandheartbrokenheresyourLouissongreccomendationPanel.Visible='on';
                [y, Fs] = audioread('thatsthewaylovegoes.mov');
                sound(y, Fs);
            end 
            if app.SadLouisButton.Value == true & app.SingleandchillingLouisButton.Value == true
                app.TabGroup.SelectedTab = app.louisanswersTab;
                app.soyouresadandsingleheresyourLouissongreccomendationPanel.Visible='on';
                [y, Fs] = audioread('twoofus.mov');
                sound(y, Fs);
            end 
            if app.SadLouisButton.Value == true & app.ItscomplicatedLouisButton.Value == true
                app.TabGroup.SelectedTab = app.louisanswersTab;
                app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel.Visible='on';
                [y, Fs] = audioread('chicago.mov');
                sound(y, Fs);
            end 

        end

        % Button pushed function: SubmitNiallButton
        function SubmitNiallButtonPushed(app, event)
            
            if app.HappyNiallButton.Value == true && app.IminloveNiallButton.Value == true
                app.TabGroup.SelectedTab = app.niallanswersTab;
                app.soyourehappyandinloveNiallPanel.Visible='on';
                [y, Fs] = audioread('heaven.mov');
                sound(y, Fs);
            end 
            if app.HappyNiallButton.Value == true & app.Imheartbroken3NiallButton.Value ==true
                app.TabGroup.SelectedTab = app.niallanswersTab;
                app.soyourehappyandheartbrokenNiallPanel.Visible='on';
                [y, Fs] = audioread('oursong.mov');
                sound(y, Fs);
            end
            if app.HappyNiallButton.Value == true & app.SingleandchillingNiallButton.Value == true
                app.TabGroup.SelectedTab = app.niallanswersTab;
                app.soyourehappyandsingleNiallPanel.Visible='on';
                [y, Fs] = audioread('onmyown.mov');
                sound(y, Fs);
            end
            if app.HappyNiallButton.Value == true & app.ItscomplicatedNiallButton.Value ==true
                app.TabGroup.SelectedTab = app.niallanswersTab;
                app.soyourehappyandcomplicatedNiallPanel.Visible='on';
                [y, Fs] = audioread('sanfrancisco.mov');
                sound(y, Fs);
            end 
            if app.SadNiallButton.Value == true & app.IminloveNiallButton.Value == true
                app.TabGroup.SelectedTab = app.niallanswersTab;
                app.soyouresadandinloveNiallPanel.Visible='on';
                [y, Fs] = audioread('pallom.mov');
                sound(y, Fs);
            end
            if app.SadNiallButton.Value == true & app.Imheartbroken3NiallButton.Value == true
                app.TabGroup.SelectedTab = app.niallanswersTab;
                app.soyouresadandheartbrokenNiallPanel.Visible='on';
                [y, Fs] = audioread('flicker.mov');
                sound(y, Fs);
            end 
            if app.SadNiallButton.Value == true & app.SingleandchillingNiallButton.Value == true
                app.TabGroup.SelectedTab = app.niallanswersTab;
                app.soyouresadandsingleNiallPanel.Visible='on';
                [y, Fs] = audioread('dearpatience.mov');
                sound(y, Fs);
            end 
            if app.SadNiallButton.Value == true & app.ItscomplicatedNiallButton.Value == true
                app.TabGroup.SelectedTab = app.niallanswersTab;
                app.soyouresadandcomplicatedNiallPanel.Visible='on';
                [y, Fs] = audioread('toomuchtoask.mov');
                sound(y, Fs);
            end  

        end

        % Button pushed function: SubmitZaynButton
        function SubmitZaynButtonPushed(app, event)
            
            if app.HappyZaynButton.Value == true && app.IminloveZaynButton.Value == true
                app.TabGroup.SelectedTab = app.zaynanswersTab;
                app.SoyourehappyandinloveZaynPanel.Visible='on';
                [y, Fs] = audioread('tightrope.mov');
                sound(y, Fs);
            end 
            if app.HappyZaynButton.Value == true & app.Imheartbroken3ZaynButton.Value ==true
                app.TabGroup.SelectedTab = app.zaynanswersTab;
                app.SoyourehappyandheartbrokenZaynPanel.Visible='on';
                [y, Fs] = audioread('befour.mov');
                sound(y, Fs);
            end
            if app.HappyZaynButton.Value == true & app.SingleandchillingZaynButton.Value == true
                app.TabGroup.SelectedTab = app.zaynanswersTab;
                app.SoyourehappyandsingleZaynPanel.Visible='on';
                [y, Fs] = audioread('goodguy.mov');
                sound(y, Fs);
            end
            if app.HappyZaynButton.Value == true & app.ItscomplicatedZaynButton.Value ==true
                app.TabGroup.SelectedTab = app.zaynanswersTab;
                app.SoyourehappyandcomplicatedZaynPanel.Visible='on';
                [y, Fs] = audioread('shedontloveme.mov');
                sound(y, Fs);
            end 
            if app.SadZaynButton.Value == true & app.IminloveZaynButton.Value == true
                app.TabGroup.SelectedTab = app.zaynanswersTab;
                app.SoyouresadandinloveZaynPanel.Visible='on';
                [y, Fs] = audioread('whenlovesaround.mov');
                sound(y, Fs);
            end
            if app.SadZaynButton.Value == true & app.Imheartbroken3ZaynButton.Value == true
                app.TabGroup.SelectedTab = app.zaynanswersTab;
                app.SoyouresadandheartbrokenZaynPanel.Visible='on';
                [y, Fs] = audioread('outside.mov');
                sound(y, Fs);
            end 
            if app.SadZaynButton.Value == true & app.SingleandchillingZaynButton.Value == true
                app.TabGroup.SelectedTab = app.zaynanswersTab;
                app.SoyouresadandsingleZaynPanel.Visible='on';
                [y, Fs] = audioread('goodyears.mov');
                sound(y, Fs);
            end 
            if app.SadZaynButton.Value == true & app.ItscomplicatedZaynButton.Value == true
                app.TabGroup.SelectedTab = app.zaynanswersTab;
                app.SoyouresadandcomplicatedZaynPanel.Visible='on';
                [y, Fs] = audioread('foolforyou.mov');
                sound(y, Fs);
            end  
        end

        % Button pushed function: SubmitLiamButton
        function SubmitLiamButtonPushed(app, event)
             
            if app.HappyLiamButton.Value == true && app.IminloveLiamButton.Value == true
                app.TabGroup.SelectedTab = app.liamanswersTab;
                app.soyourehappyandinloveLiamPanel.Visible='on';
                [y, Fs] = audioread('midnight.mov');
                sound(y, Fs);
            end 
            if app.HappyLiamButton.Value == true & app.Imheartbroken3LiamButton.Value ==true
                app.TabGroup.SelectedTab = app.liamanswersTab;
                app.SoyourehappyandheartbrokenLiamPanel.Visible='on';
                [y, Fs] = audioread('heartmeetbreak.mov');
                sound(y, Fs);
            end
            if app.HappyLiamButton.Value == true & app.SingleandchillingLiamButton.Value == true
                app.TabGroup.SelectedTab = app.liamanswersTab;
                app.SoyourehappyandsingleLiamPanel.Visible='on';
                [y, Fs] = audioread('sunshine.mov');
                sound(y, Fs);
            end
            if app.HappyLiamButton.Value == true & app.ItscomplicatedLiamButton.Value ==true
                app.TabGroup.SelectedTab = app.liamanswersTab;
                app.SoyourehappyandcomplicatedLiamPanel.Visible='on';
                [y, Fs] = audioread('tellyourfriends.mov');
                sound(y, Fs);
            end 
            if app.HornyLiamButton.Value == true & app.IminloveLiamButton.Value == true
                app.TabGroup.SelectedTab = app.liamanswersTab;
                app.soyourehornyandinloveLiamPanel.Visible='on';
                [y, Fs] = audioread('stripthatdown.mov');
                sound(y, Fs);
            end
            if app.HornyLiamButton.Value == true & app.Imheartbroken3LiamButton.Value == true
                app.TabGroup.SelectedTab = app.liamanswersTab;
                app.SoyourehornyandheartbrokenLiamPanel.Visible='on';
                [y, Fs] = audioread('polaroid.mov');
                sound(y, Fs);
            end 
            if app.HornyLiamButton.Value == true & app.SingleandchillingLiamButton.Value == true
                app.TabGroup.SelectedTab = app.liamanswersTab;
                app.SoyourehornyandsingleLiamPanel.Visible='on';
                [y, Fs] = audioread('weekend.mov');
                sound(y, Fs);
            end 
            if app.HornyLiamButton.Value == true & app.ItscomplicatedLiamButton.Value == true
                app.TabGroup.SelectedTab = app.liamanswersTab;
                app.SoyourehornyandcomplicatedLiamPanel.Visible='on';
                [y, Fs] = audioread('rudehours.mov');
                sound(y, Fs);
            end  
        end

        % Button pushed function: SubmitTaylorButton
        function SubmitTaylorButtonPushed(app, event)
            if  app.HappyTaylorButton.Value == true && app.IminloveTaylorButton.Value == true
                app.TabGroup.SelectedTab = app.tayloranswersTab;
                app.soyourehappyandinloveTaylorPanel.Visible='on';
                [y, Fs] = audioread('Taylor.mov');
                sound(y, Fs);
            end 
            if app.HappyTaylorButton.Value == true & app.ImheartbrokenTaylorButton.Value ==true
                app.TabGroup.SelectedTab = app.tayloranswersTab;
                app.soyourehappyandheartbrokenTaylorPanel.Visible='on';
                [y, Fs] = audioread('cruelsummer.mov');
                sound(y, Fs);
            end
            if app.HappyTaylorButton.Value == true & app.SingleandchillingTaylorButton.Value == true
                app.TabGroup.SelectedTab = app.tayloranswersTab;
                app.soyourehappyandsingleTaylorPanel.Visible='on';
                [y, Fs] = audioread('ididsomethingbad.mov');
                sound(y, Fs);
            end
            if app.HappyTaylorButton.Value == true & app.ItscomplicatedTaylorButton.Value ==true
                app.TabGroup.SelectedTab = app.tayloranswersTab;
                app.soyourehappyandcomplicatedTaylorPanel.Visible='on';
                [y, Fs] = audioread('getawaycar.mov');
                sound(y, Fs);
            end 
            if app.SadTaylorButton.Value == true & app.IminloveTaylorButton.Value == true
                app.TabGroup.SelectedTab = app.tayloranswersTab;
                app.soyouresadandinloveTaylorPanel.Visible='on';
                [y, Fs] = audioread('wildestdreams.mov');
                sound(y, Fs);
            end
            if app.SadTaylorButton.Value == true & app.ImheartbrokenTaylorButton.Value == true
                app.TabGroup.SelectedTab = app.tayloranswersTab;
                app.soyouresadandheartbrokenTaylorPanel.Visible='on';
                [y, Fs] = audioread('alltoowell.mov');
                sound(y, Fs);
            end 
            if app.SadTaylorButton.Value == true & app.SingleandchillingTaylorButton.Value == true
                app.TabGroup.SelectedTab = app.tayloranswersTab;
                app.soyouresadandsingleTaylorPanel.Visible='on';
                [y, Fs] = audioread('thearcher.mov');
                sound(y, Fs);
            end 
            if app.SadTaylorButton.Value == true & app.ItscomplicatedTaylorButton.Value == true
                app.TabGroup.SelectedTab = app.tayloranswersTab;
                app.soyouresadandcomplicatedTaylorPanel.Visible='on';
                [y, Fs] = audioread('illicitaffairs.mov');
                sound(y, Fs);
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Q4WhoisyourfavoriteoftheseartistsButtonGroup = uibuttongroup(app.Tab);
            app.Q4WhoisyourfavoriteoftheseartistsButtonGroup.Title = 'Q4 Who is your favorite of these artists?';
            app.LizzoButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.LizzoButton.Text = 'Lizzo';
            app.LizzoButton.Value = true;
            app.SteveAokiButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.SteveAokiButton.Text = 'Steve Aoki';
            app.LewisCapaldiButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.LewisCapaldiButton.Text = 'Lewis Capaldi';
            app.SiaButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.SiaButton.Text = 'Sia';
            app.ABoogieButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.ABoogieButton.Text = 'A Boogie';
            app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup = uibuttongroup(app.Tab);
            app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup.Title = 'Q5 Which would you rather do in your free time?';
            app.PilatesmeditationButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.PilatesmeditationButton.Text = 'Pilates & meditation';
            app.PilatesmeditationButton.Value = true;
            app.PlaysoccerButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.PlaysoccerButton.Text = 'Play soccer';
            app.GolfButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.GolfButton.Text = 'Golf';
            app.DraworpaintButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.DraworpaintButton.Text = 'Draw or paint';
            app.GooutButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.GooutButton.Text = 'Go out';
            app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup = uibuttongroup(app.Tab);
            app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup.Title = 'Q6 Which role do you have in your friend group?';
            app.MomfriendButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.MomfriendButton.Text = 'Mom friend';
            app.MomfriendButton.Value = true;
            app.ClassclownButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.ClassclownButton.Text = 'Class clown';
            app.CrazyuncleButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.CrazyuncleButton.Text = 'Crazy uncle';
            app.TheonepeoplegotoforadviceButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.TheonepeoplegotoforadviceButton.Text = 'The one people go to for advice';
            app.DadfriendButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.DadfriendButton.Text = 'Dad friend';
            app.Q7WhatisyourfavoritecolorButtonGroup = uibuttongroup(app.Tab);
            app.Q7WhatisyourfavoritecolorButtonGroup.Title = 'Q7 What is your favorite color?';
            app.GreenButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.GreenButton.Value = true;
            app.BlueButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.OrangeButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.OrangeButton.Text = 'Orange';
            app.YellowButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.YellowButton.Text = 'Yellow';
            app.RedButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.RedButton.Text = 'Red';
            app.Q8WhichpetwouldyouratherhaveButtonGroup = uibuttongroup(app.Tab);
            app.Q8WhichpetwouldyouratherhaveButtonGroup.Title = 'Q8 Which pet would you rather have?';
            app.CalicokittenButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.CalicokittenButton.Text = {'Calico kitten'; ''};
            app.CalicokittenButton.Value = true;
            app.AustralianshepherdpuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.AustralianshepherdpuppyButton.Text = 'Australian shepherd puppy ';
            app.GoldenretrieverpuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.GoldenretrieverpuppyButton.Text = 'Golden retriever puppy';
            app.BlackkittenButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.BlackkittenButton.Text = 'Black kitten';
            app.BeaglepuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.BeaglepuppyButton.Text = 'Beagle puppy ';
            app.Q8WhichpetwouldyouratherhaveButtonGroup_2 = uibuttongroup(app.Tab);
            app.Q8WhichpetwouldyouratherhaveButtonGroup_2.Title = 'Q9 If you weren’t studying CLPS, what else would you want to study at Brown?';
            app.LiteraryartsButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_2);
            app.LiteraryartsButton.Text = 'Literary arts ';
            app.LiteraryartsButton.Value = true;
            app.MCMButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_2);
            app.MCMButton.Text = 'MCM';
            app.HistoryButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_2);
            app.HistoryButton.Text = 'History';
            app.VISAButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_2);
            app.VISAButton.Text = 'VISA';
            app.APMAEconwithanentrepreneurshipcertificateButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_2);
            app.APMAEconwithanentrepreneurshipcertificateButton.Text = 'APMA Econ with an entrepreneurship certificate ';
            app.Q3WhatwouldyouridealfirstdatebeButtonGroup = uibuttongroup(app.Tab);
            app.Q3WhatwouldyouridealfirstdatebeButtonGroup.Title = 'Q3 What would your ideal first date be?';
            app.iceskatingButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.iceskatingButton.Text = 'ice skating';
            app.iceskatingButton.Value = true;
            app.goingforadriveButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.goingforadriveButton.Text = 'going for a drive';
            app.stayinginandplayinggamesButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.stayinginandplayinggamesButton.Text = 'staying in and playing games';
            app.fancydinnerdateButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.fancydinnerdateButton.Text = 'fancy dinner date';
            app.goingtoacarnivalButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.goingtoacarnivalButton.Text = 'going to a carnival';
            app.Q2WhatisyourfavoriteseasonButtonGroup = uibuttongroup(app.Tab);
            app.Q2WhatisyourfavoriteseasonButtonGroup.Title = 'Q2 What is your favorite season?';
            app.summerButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.summerButton.Text = 'summer';
            app.summerButton.Value = true;
            app.winterButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.winterButton.Text = 'winter';
            app.springButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.springButton.Text = 'spring';
            app.fallButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.fallButton.Text = 'fall';
            app.IlikethemallButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.IlikethemallButton.Text = 'I like them all!';
            app.Q1WhatisyourfavoritefoodButtonGroup = uibuttongroup(app.Tab);
            app.Q1WhatisyourfavoritefoodButtonGroup.Title = 'Q1 What is your favorite food?';
            app.fruitsandveggiesButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.fruitsandveggiesButton.Text = 'fruits and veggies';
            app.fruitsandveggiesButton.Value = true;
            app.fastfoodButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.fastfoodButton.Text = 'fast food';
            app.WhoneedsfoodwhenyouhavebeerButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.WhoneedsfoodwhenyouhavebeerButton.Text = 'Who needs food when you have beer?';
            app.pastaButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.pastaButton.Text = 'pasta!!';
            app.MexicanfoodButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.MexicanfoodButton.Text = 'Mexican food';
            app.SUBMITButton = uibutton(app.Tab, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.WhichOneDirectionMemberAreYouLabel = uilabel(app.Tab);
            app.WhichOneDirectionMemberAreYouLabel.Text = 'Which One Direction Member Are You?';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_8323.JPG');
            app.Image2 = uiimage(app.Tab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '73ef9ca68981d5688ce7673ec4001abb.jpg');
            app.Image3 = uiimage(app.Tab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '68305cf6fa5c7a4b56bac69cd797e812.jpg');
            app.Image4 = uiimage(app.Tab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'c45258f08ee609c62cb79d58ac394eba.jpg');
            app.Image5 = uiimage(app.Tab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'cb1d109659d69e6bf0d3c9c9aa37934f.jpg');
            app.RESETButton = uibutton(app.Tab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.FirstQuizAnswersTab = uitab(app.TabGroup);
            app.FirstQuizAnswersTab.Title = 'Tab';
            app.WhichOneDirectionMemberAreYouPanel_Liam = uipanel(app.FirstQuizAnswersTab);
            app.WhichOneDirectionMemberAreYouPanel_Liam.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_Liam.Visible = 'off';
            app.TextArea = uitextarea(app.WhichOneDirectionMemberAreYouPanel_Liam);
            app.TextArea.Value = {''; 'LIAM PAYNE'};
            app.continueliamButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_Liam, 'push');
            app.continueliamButton.ButtonPushedFcn = createCallbackFcn(app, @continueliamButtonPushed, true);
            app.continueliamButton.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_Taylor = uipanel(app.FirstQuizAnswersTab);
            app.WhichOneDirectionMemberAreYouPanel_Taylor.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_Taylor.Visible = 'off';
            app.TextArea_2 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_Taylor);
            app.TextArea_2.Value = {'omg?!? you''re NOT a 1D member... you''re TAYLOR SWIFT'};
            app.continuetaylorButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_Taylor, 'push');
            app.continuetaylorButton.ButtonPushedFcn = createCallbackFcn(app, @continuetaylorButtonPushed, true);
            app.continuetaylorButton.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_Harry = uipanel(app.FirstQuizAnswersTab);
            app.WhichOneDirectionMemberAreYouPanel_Harry.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_Harry.Visible = 'off';
            app.TextArea_3 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_Harry);
            app.TextArea_3.Value = {''; 'HARRY STYLES'};
            app.continueharryButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_Harry, 'push');
            app.continueharryButton.ButtonPushedFcn = createCallbackFcn(app, @continueharryButtonPushed, true);
            app.continueharryButton.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_Niall = uipanel(app.FirstQuizAnswersTab);
            app.WhichOneDirectionMemberAreYouPanel_Niall.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_Niall.Visible = 'off';
            app.TextArea_4 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_Niall);
            app.TextArea_4.Value = {''; 'NIALL HORAN'};
            app.continueniallButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_Niall, 'push');
            app.continueniallButton.ButtonPushedFcn = createCallbackFcn(app, @continueniallButtonPushed, true);
            app.continueniallButton.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_zayn = uipanel(app.FirstQuizAnswersTab);
            app.WhichOneDirectionMemberAreYouPanel_zayn.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_zayn.Visible = 'off';
            app.TextArea_6 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_zayn);
            app.TextArea_6.Value = {''; 'ZAYN MALIK'};
            app.continueButton_zayn = uibutton(app.WhichOneDirectionMemberAreYouPanel_zayn, 'push');
            app.continueButton_zayn.ButtonPushedFcn = createCallbackFcn(app, @continueButton_zaynPushed, true);
            app.continueButton_zayn.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_louis = uipanel(app.FirstQuizAnswersTab);
            app.WhichOneDirectionMemberAreYouPanel_louis.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_louis.Visible = 'off';
            app.TextArea_5 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_louis);
            app.TextArea_5.Value = {'LOUIS TOMLINSON'};
            app.continuelouisButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_louis, 'push');
            app.continuelouisButton.ButtonPushedFcn = createCallbackFcn(app, @continuelouisButtonPushed, true);
            app.continuelouisButton.Text = 'continue';
            app.soyourebutTab = uitab(app.TabGroup);
            app.soyourebutTab.Title = 'Tab4';
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel = uipanel(app.soyourebutTab);
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Title = 'So you''re Louis Tomlinson...but what Louis Tomlinson song are you?';
            app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup = uibuttongroup(app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel);
            app.HowareyoufeelingButtonGroup.Title = 'How are you feeling?';
            app.HappyLouisButton = uiradiobutton(app.HowareyoufeelingButtonGroup);
            app.HappyLouisButton.Text = 'Happy';
            app.HappyLouisButton.Value = true;
            app.SadLouisButton = uiradiobutton(app.HowareyoufeelingButtonGroup);
            app.SadLouisButton.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup = uibuttongroup(app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup.Title = 'What''s your relationship status?';
            app.IminloveLouisButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.IminloveLouisButton.Text = 'I''m in love :)';
            app.IminloveLouisButton.Value = true;
            app.Imheartbroken3LouisButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.Imheartbroken3LouisButton.Text = 'I''m heartbroken </3';
            app.SingleandchillingLouisButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.SingleandchillingLouisButton.Text = 'Single and chilling';
            app.ItscomplicatedLouisButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.ItscomplicatedLouisButton.Text = 'It''s complicated...';
            app.SubmitLouisButton = uibutton(app.SoyoureLouisTomlinsonbutwhatLouisTomlinsonsongareyouPanel, 'push');
            app.SubmitLouisButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitLouisButtonPushed, true);
            app.SubmitLouisButton.Text = 'Submit';
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel = uipanel(app.soyourebutTab);
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Title = 'So you''re Harry Styles...but what Harry Styles song are you?';
            app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_5 = uibuttongroup(app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel);
            app.HowareyoufeelingButtonGroup_5.Title = 'How are you feeling?';
            app.HappyHarryButton = uiradiobutton(app.HowareyoufeelingButtonGroup_5);
            app.HappyHarryButton.Text = 'Happy';
            app.HappyHarryButton.Value = true;
            app.SadHarryButton = uiradiobutton(app.HowareyoufeelingButtonGroup_5);
            app.SadHarryButton.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_5 = uibuttongroup(app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_5.Title = 'What''s your relationship status?';
            app.IminloveHarryButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.IminloveHarryButton.Text = 'I''m in love :)';
            app.IminloveHarryButton.Value = true;
            app.Imheartbroken3HarryButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.Imheartbroken3HarryButton.Text = 'I''m heartbroken </3';
            app.SingleandchillingHarryButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.SingleandchillingHarryButton.Text = 'Single and chilling';
            app.ItscomplicatedHarryButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.ItscomplicatedHarryButton.Text = 'It''s complicated...';
            app.SubmitHarryButton = uibutton(app.SoyoureHarryStylesbutwhatHarryStylessongareyouPanel, 'push');
            app.SubmitHarryButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitHarryButtonPushed, true);
            app.SubmitHarryButton.Text = 'Submit';
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel = uipanel(app.soyourebutTab);
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Title = 'So you''re Zayn Malik...but what Zayn Malik song are you?';
            app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_4 = uibuttongroup(app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel);
            app.HowareyoufeelingButtonGroup_4.Title = 'How are you feeling?';
            app.HappyZaynButton = uiradiobutton(app.HowareyoufeelingButtonGroup_4);
            app.HappyZaynButton.Text = 'Happy';
            app.HappyZaynButton.Value = true;
            app.SadZaynButton = uiradiobutton(app.HowareyoufeelingButtonGroup_4);
            app.SadZaynButton.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_4 = uibuttongroup(app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_4.Title = 'What''s your relationship status?';
            app.IminloveZaynButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.IminloveZaynButton.Text = 'I''m in love :)';
            app.IminloveZaynButton.Value = true;
            app.Imheartbroken3ZaynButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.Imheartbroken3ZaynButton.Text = 'I''m heartbroken </3';
            app.SingleandchillingZaynButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.SingleandchillingZaynButton.Text = 'Single and chilling';
            app.ItscomplicatedZaynButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.ItscomplicatedZaynButton.Text = 'It''s complicated...';
            app.SubmitZaynButton = uibutton(app.SoyoureZaynMalikbutwhatZaynMaliksongareyouPanel, 'push');
            app.SubmitZaynButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitZaynButtonPushed, true);
            app.SubmitZaynButton.Text = 'Submit';
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel = uipanel(app.soyourebutTab);
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Title = 'So you''re Liam Payne...but what Liam Payne song are you?';
            app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_6 = uibuttongroup(app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel);
            app.HowareyoufeelingButtonGroup_6.Title = 'How are you feeling?';
            app.HappyLiamButton = uiradiobutton(app.HowareyoufeelingButtonGroup_6);
            app.HappyLiamButton.Text = 'Happy';
            app.HappyLiamButton.Value = true;
            app.HornyLiamButton = uiradiobutton(app.HowareyoufeelingButtonGroup_6);
            app.HornyLiamButton.Text = 'Horny';
            app.WhatsyourrelationshipstatusButtonGroup_6 = uibuttongroup(app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_6.Title = 'What''s your relationship status?';
            app.IminloveLiamButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.IminloveLiamButton.Text = 'I''m in love :)';
            app.IminloveLiamButton.Value = true;
            app.Imheartbroken3LiamButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.Imheartbroken3LiamButton.Text = 'I''m heartbroken </3';
            app.SingleandchillingLiamButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.SingleandchillingLiamButton.Text = 'Single and chilling';
            app.ItscomplicatedLiamButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_6);
            app.ItscomplicatedLiamButton.Text = 'It''s complicated...';
            app.SubmitLiamButton = uibutton(app.SoyoureLiamPaynebutwhatLiamPaynesongareyouPanel, 'push');
            app.SubmitLiamButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitLiamButtonPushed, true);
            app.SubmitLiamButton.Text = 'Submit';
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel = uipanel(app.soyourebutTab);
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Title = 'So you''re Niall Horan...but what Niall Horan song are you?';
            app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_3 = uibuttongroup(app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel);
            app.HowareyoufeelingButtonGroup_3.Title = 'How are you feeling?';
            app.HappyNiallButton = uiradiobutton(app.HowareyoufeelingButtonGroup_3);
            app.HappyNiallButton.Text = 'Happy';
            app.HappyNiallButton.Value = true;
            app.SadNiallButton = uiradiobutton(app.HowareyoufeelingButtonGroup_3);
            app.SadNiallButton.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_3 = uibuttongroup(app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_3.Title = 'What''s your relationship status?';
            app.IminloveNiallButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.IminloveNiallButton.Text = 'I''m in love :)';
            app.IminloveNiallButton.Value = true;
            app.Imheartbroken3NiallButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.Imheartbroken3NiallButton.Text = 'I''m heartbroken </3';
            app.SingleandchillingNiallButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.SingleandchillingNiallButton.Text = 'Single and chilling';
            app.ItscomplicatedNiallButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.ItscomplicatedNiallButton.Text = 'It''s complicated...';
            app.SubmitNiallButton = uibutton(app.SoyoureNiallHoranbutwhatNiallHoransongareyouPanel, 'push');
            app.SubmitNiallButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitNiallButtonPushed, true);
            app.SubmitNiallButton.Text = 'Submit';
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel = uipanel(app.soyourebutTab);
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Title = 'So you''re Taylor Swift...but what Taylor Swift song are you?';
            app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_2 = uibuttongroup(app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel);
            app.HowareyoufeelingButtonGroup_2.Title = 'How are you feeling?';
            app.HappyTaylorButton = uiradiobutton(app.HowareyoufeelingButtonGroup_2);
            app.HappyTaylorButton.Text = 'Happy';
            app.HappyTaylorButton.Value = true;
            app.SadTaylorButton = uiradiobutton(app.HowareyoufeelingButtonGroup_2);
            app.SadTaylorButton.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_2 = uibuttongroup(app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel);
            app.WhatsyourrelationshipstatusButtonGroup_2.Title = 'What''s your relationship status?';
            app.IminloveTaylorButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.IminloveTaylorButton.Text = 'I''m in love :)';
            app.IminloveTaylorButton.Value = true;
            app.ImheartbrokenTaylorButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.ImheartbrokenTaylorButton.Text = 'I''m heartbroken </3';
            app.SingleandchillingTaylorButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.SingleandchillingTaylorButton.Text = 'Single and chilling';
            app.ItscomplicatedTaylorButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.ItscomplicatedTaylorButton.Text = 'It''s complicated...';
            app.SubmitTaylorButton = uibutton(app.SoyoureTaylorSwiftbutwhatTaylorSwiftsongareyouPanel, 'push');
            app.SubmitTaylorButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitTaylorButtonPushed, true);
            app.SubmitTaylorButton.Text = 'Submit';
            app.harryanswersTab = uitab(app.TabGroup);
            app.harryanswersTab.Title = 'Tab2';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel = uipanel(app.harryanswersTab);
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Title = 'So you''re happy and in love...here''s your Harry Styles song recommendation!';
            app.soyourehappyandinloveheresyourHarrysongreccomendationPanel.Visible = 'off';
            app.EditField = uieditfield(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel, 'text');
            app.EditField.Value = 'Sunflower Vol. 6';
            app.Image6 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.05.49 AM.png');
            app.Image6_2 = uiimage(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.Image6_2.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.05.49 AM.png');
            app.Hyperlink2 = uihyperlink(app.soyourehappyandinloveheresyourHarrysongreccomendationPanel);
            app.Hyperlink2.URL = 'https://open.spotify.com/track/6iYMfxznTBlcVOgRHab2W0?si=6ae3059c87134601';
            app.Hyperlink2.Text = 'click here to listen';
            app.soyoursadanditscomplicatedHarryPanel_3 = uipanel(app.harryanswersTab);
            app.soyoursadanditscomplicatedHarryPanel_3.Title = 'So you''re sad and it''s complicated...here''s your Harry Styles song recommendation!';
            app.soyoursadanditscomplicatedHarryPanel_3.Visible = 'off';
            app.EditField_18 = uieditfield(app.soyoursadanditscomplicatedHarryPanel_3, 'text');
            app.EditField_18.Value = 'Girl Crush (cover)';
            app.Image6_31 = uiimage(app.soyoursadanditscomplicatedHarryPanel_3);
            app.Image6_31.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.55.57 PM.png');
            app.Image6_32 = uiimage(app.soyoursadanditscomplicatedHarryPanel_3);
            app.Image6_32.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.55.57 PM.png');
            app.Hyperlink2_11 = uihyperlink(app.soyoursadanditscomplicatedHarryPanel_3);
            app.Hyperlink2_11.URL = 'https://open.spotify.com/track/4YutJsNwBBInE8vemufpQ8?si=5f87420d1a73490d';
            app.Hyperlink2_11.Text = 'click here to listen';
            app.soyourehappyandheartbrokenHarryPanel = uipanel(app.harryanswersTab);
            app.soyourehappyandheartbrokenHarryPanel.Title = 'So you''re happy and heartbroken...here''s your Harry Styles song recommendation!';
            app.soyourehappyandheartbrokenHarryPanel.Visible = 'off';
            app.EditField_11 = uieditfield(app.soyourehappyandheartbrokenHarryPanel, 'text');
            app.EditField_11.Value = 'Carolina';
            app.Image6_18 = uiimage(app.soyourehappyandheartbrokenHarryPanel);
            app.Image6_18.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.08.57 AM.png');
            app.Image6_16 = uiimage(app.soyourehappyandheartbrokenHarryPanel);
            app.Image6_16.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.08.57 AM.png');
            app.soyoursadandinloveHarryPanel = uipanel(app.harryanswersTab);
            app.soyoursadandinloveHarryPanel.Title = 'So you''re sad and in love...here''s your Harry Styles song recommendation!';
            app.soyoursadandinloveHarryPanel.Visible = 'off';
            app.EditField_12 = uieditfield(app.soyoursadandinloveHarryPanel, 'text');
            app.EditField_12.Value = 'Love of My Life';
            app.Image6_19 = uiimage(app.soyoursadandinloveHarryPanel);
            app.Image6_19.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.08.30 PM.png');
            app.Image6_20 = uiimage(app.soyoursadandinloveHarryPanel);
            app.Image6_20.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.08.30 PM.png');
            app.Hyperlink2_5 = uihyperlink(app.soyoursadandinloveHarryPanel);
            app.Hyperlink2_5.URL = 'https://open.spotify.com/track/1mw0RgNXIpYRyyCdBQbLgA?si=178e562ea5ef41f9';
            app.Hyperlink2_5.Text = 'click here to listen';
            app.soyoursadandheartbrokenHarryPanel = uipanel(app.harryanswersTab);
            app.soyoursadandheartbrokenHarryPanel.Title = 'So you''re sad and heartbroken...here''s your Harry Styles song recommendation!';
            app.soyoursadandheartbrokenHarryPanel.Visible = 'off';
            app.EditField_13 = uieditfield(app.soyoursadandheartbrokenHarryPanel, 'text');
            app.EditField_13.Value = 'Falling';
            app.Image6_21 = uiimage(app.soyoursadandheartbrokenHarryPanel);
            app.Image6_21.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.11.36 PM.png');
            app.Image6_22 = uiimage(app.soyoursadandheartbrokenHarryPanel);
            app.Image6_22.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.11.36 PM.png');
            app.Hyperlink2_6 = uihyperlink(app.soyoursadandheartbrokenHarryPanel);
            app.Hyperlink2_6.URL = 'https://open.spotify.com/track/1ZMiCix7XSAbfAJlEZWMCp?si=c50bbb86f7bc412c';
            app.Hyperlink2_6.Text = 'click here to listen';
            app.soyourhappyanditscomplicatedHarryPanel = uipanel(app.harryanswersTab);
            app.soyourhappyanditscomplicatedHarryPanel.Title = 'So you''re happy and complicated...here''s your Harry Styles song recommendation!';
            app.soyourhappyanditscomplicatedHarryPanel.Visible = 'off';
            app.EditField_9 = uieditfield(app.soyourhappyanditscomplicatedHarryPanel, 'text');
            app.EditField_9.Value = 'Satellite';
            app.Image6_10 = uiimage(app.soyourhappyanditscomplicatedHarryPanel);
            app.Image6_10.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.19.13 AM.png');
            app.Image6_11 = uiimage(app.soyourhappyanditscomplicatedHarryPanel);
            app.Image6_11.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.19.13 AM.png');
            app.Image6_12 = uiimage(app.soyourhappyanditscomplicatedHarryPanel);
            app.Image6_12.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.19.13 AM.png');
            app.Hyperlink2_2 = uihyperlink(app.soyourhappyanditscomplicatedHarryPanel);
            app.Hyperlink2_2.URL = 'https://open.spotify.com/track/0rzaRSujxA0bKyjJl6vHYq?si=c7e413df07aa408e';
            app.Hyperlink2_2.Text = 'click here to listen';
            app.soyoursadandsingleHarryPanel = uipanel(app.harryanswersTab);
            app.soyoursadandsingleHarryPanel.Title = 'So you''re sad and single...here''s your Harry Styles song recommendation!';
            app.soyoursadandsingleHarryPanel.Visible = 'off';
            app.EditField_14 = uieditfield(app.soyoursadandsingleHarryPanel, 'text');
            app.EditField_14.Value = 'Fine Line';
            app.your = uiimage(app.soyoursadandsingleHarryPanel);
            app.your.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.48.42 PM.png');
            app.Image6_23 = uiimage(app.soyoursadandsingleHarryPanel);
            app.Image6_23.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 9.48.42 PM.png');
            app.Hyperlink2_7 = uihyperlink(app.soyoursadandsingleHarryPanel);
            app.Hyperlink2_7.URL = 'https://open.spotify.com/track/6VzcQuzTNTMFnJ6rBSaLH9?si=e7acdf6bfc094ddfhttps://open.spotify.com/track/6VzcQuzTNTMFnJ6rBSaLH9?si=e7acdf6bfc094ddf';
            app.Hyperlink2_7.Text = 'click here to listen';
            app.soyourhappyandsingleHarryPanel = uipanel(app.harryanswersTab);
            app.soyourhappyandsingleHarryPanel.Title = 'So you''re happy and single...here''s your Harry Styles song recommendation!';
            app.soyourhappyandsingleHarryPanel.Visible = 'off';
            app.EditField_19 = uieditfield(app.soyourhappyandsingleHarryPanel, 'text');
            app.EditField_19.Value = 'Canyon Moon';
            app.Image6_35 = uiimage(app.soyourhappyandsingleHarryPanel);
            app.Image6_35.ImageSource = fullfile(pathToMLAPP, 'canyonmoon-10 copy.jpg');
            app.Hyperlink2_12 = uihyperlink(app.soyourhappyandsingleHarryPanel);
            app.Hyperlink2_12.URL = 'https://open.spotify.com/track/3Dwq1oiOFys6Sxr5SslmXw?si=66fe45c6d8e94911';
            app.Hyperlink2_12.Text = 'click here to listen';
            app.louisanswersTab = uitab(app.TabGroup);
            app.louisanswersTab.Title = 'Tab3';
            app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel = uipanel(app.louisanswersTab);
            app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel.Title = 'So you''re happy and heartbroken...here''s your Louis Tomlinson song rec!';
            app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_3 = uieditfield(app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel, 'text');
            app.EditField_3.Value = 'Always You';
            app.Image6_4 = uiimage(app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel);
            app.Image6_4.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.35.30 AM.png');
            app.Hyperlink_2 = uihyperlink(app.soyourehappyandheartbrokenheresyourLouissongreccomendationPanel);
            app.Hyperlink_2.URL = 'https://open.spotify.com/track/5KcT9rJDqQJUSFF3O9wPJ5?si=adad9c32f9c94d31';
            app.Hyperlink_2.Text = 'click to listen!';
            app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel = uipanel(app.louisanswersTab);
            app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel.Title = 'So you''re happy and complicated...here''s your Louis Tomlinson song recommendation!';
            app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_5 = uieditfield(app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel, 'text');
            app.EditField_5.Value = 'Defenceless';
            app.Image6_6 = uiimage(app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel);
            app.Image6_6.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.52.16 AM.png');
            app.Hyperlink_4 = uihyperlink(app.soyourehappyandcomplicatedheresyourLouissongreccomendationPanel);
            app.Hyperlink_4.URL = 'https://open.spotify.com/track/4nbQRLAPq202lsQsv9FNwK?si=b566e8c59bb04482';
            app.Hyperlink_4.Text = 'click to listen!';
            app.soyourehappyandsingleheresyourLouissongreccomendationPanel = uipanel(app.louisanswersTab);
            app.soyourehappyandsingleheresyourLouissongreccomendationPanel.Title = 'So you''re happy and single...here''s your Louis Tomlinson song recommendation!';
            app.soyourehappyandsingleheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_4 = uieditfield(app.soyourehappyandsingleheresyourLouissongreccomendationPanel, 'text');
            app.EditField_4.Value = 'Silver Tongues';
            app.Image6_5 = uiimage(app.soyourehappyandsingleheresyourLouissongreccomendationPanel);
            app.Image6_5.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.49.39 AM.png');
            app.Hyperlink_3 = uihyperlink(app.soyourehappyandsingleheresyourLouissongreccomendationPanel);
            app.Hyperlink_3.URL = 'https://open.spotify.com/track/29z96EFvG0L1wcj5kkIHzk?si=a3ec697c3c1f4b07d9cdwCF0rF04LjDi?si=be4aeeaf81ba4d96';
            app.Hyperlink_3.Text = 'click to listen!';
            app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel = uipanel(app.louisanswersTab);
            app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel.Title = 'So you''re sad and complicated...here''s your Louis Tomlinson song rec!';
            app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_8 = uieditfield(app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel, 'text');
            app.EditField_8.Value = 'Chicago';
            app.Image6_9 = uiimage(app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel);
            app.Image6_9.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.04.23 AM.png');
            app.Hyperlink_7 = uihyperlink(app.soyouresadandcomplicatedheresyourLouissongreccomendationPanel);
            app.Hyperlink_7.URL = 'https://open.spotify.com/track/6ypAO1oxZUBVeHtdFOi3Cj?si=e8918d5ad2d3413b';
            app.Hyperlink_7.Text = 'click to listen!';
            app.soyouresadandinloveheresyourLouissongreccomendationPanel = uipanel(app.louisanswersTab);
            app.soyouresadandinloveheresyourLouissongreccomendationPanel.Title = 'So you''re sad and in love...here''s your Louis Tomlinson song recommendation!';
            app.soyouresadandinloveheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_20 = uieditfield(app.soyouresadandinloveheresyourLouissongreccomendationPanel, 'text');
            app.EditField_20.Value = 'Habit';
            app.Image6_36 = uiimage(app.soyouresadandinloveheresyourLouissongreccomendationPanel);
            app.Image6_36.ImageSource = fullfile(pathToMLAPP, '42326f95309c8489f21216f62c6c0632.jpg');
            app.Hyperlink_8 = uihyperlink(app.soyouresadandinloveheresyourLouissongreccomendationPanel);
            app.Hyperlink_8.URL = 'https://open.spotify.com/track/3As9Uyx1ZYKbdcEn3d7zma?si=80043438139f47cd';
            app.Hyperlink_8.Text = 'click to listen!';
            app.soyouresadandsingleheresyourLouissongreccomendationPanel = uipanel(app.louisanswersTab);
            app.soyouresadandsingleheresyourLouissongreccomendationPanel.Title = 'So you''re sad and single...here''s your Louis Tomlinson song rec!';
            app.soyouresadandsingleheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_6 = uieditfield(app.soyouresadandsingleheresyourLouissongreccomendationPanel, 'text');
            app.EditField_6.Value = 'Two of Us';
            app.Image6_7 = uiimage(app.soyouresadandsingleheresyourLouissongreccomendationPanel);
            app.Image6_7.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 2.01.15 AM.png');
            app.Hyperlink_5 = uihyperlink(app.soyouresadandsingleheresyourLouissongreccomendationPanel);
            app.Hyperlink_5.URL = 'https://open.spotify.com/track/1eqwjXdMTed8Pz3PLNcHe9?si=9a23bed036d140d9';
            app.Hyperlink_5.Text = 'click to listen!';
            app.soyouresadandheartbrokenheresyourLouissongreccomendationPanel = uipanel(app.louisanswersTab);
            app.soyouresadandheartbrokenheresyourLouissongreccomendationPanel.Title = 'So you''re sad and heartbroken...here''s your Louis Tomlinson song rec!';
            app.soyouresadandheartbrokenheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_7 = uieditfield(app.soyouresadandheartbrokenheresyourLouissongreccomendationPanel, 'text');
            app.EditField_7.Value = 'That''s The Way Love Goes';
            app.Image6_8 = uiimage(app.soyouresadandheartbrokenheresyourLouissongreccomendationPanel);
            app.Image6_8.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 1.54.54 AM.png');
            app.Hyperlink_6 = uihyperlink(app.soyouresadandheartbrokenheresyourLouissongreccomendationPanel);
            app.Hyperlink_6.URL = 'https://open.spotify.com/track/4o4f31HSyquyh0ANKJsgnb?si=4ce398e0529043b2';
            app.Hyperlink_6.Text = 'click to listen!';
            app.soyourehappyandinloveheresyourLouissongreccomendationPanel = uipanel(app.louisanswersTab);
            app.soyourehappyandinloveheresyourLouissongreccomendationPanel.Title = 'So you''re happy and in love...here''s your Louis Tomlinson song rec!';
            app.soyourehappyandinloveheresyourLouissongreccomendationPanel.Visible = 'off';
            app.EditField_2 = uieditfield(app.soyourehappyandinloveheresyourLouissongreccomendationPanel, 'text');
            app.EditField_2.Value = 'Kill My Mind';
            app.Image6_3 = uiimage(app.soyourehappyandinloveheresyourLouissongreccomendationPanel);
            app.Image6_3.ImageSource = fullfile(pathToMLAPP, '7e724e316fb28e5146ee70c6507aeffb.jpg');
            app.Hyperlink = uihyperlink(app.soyourehappyandinloveheresyourLouissongreccomendationPanel);
            app.Hyperlink.URL = 'https://open.spotify.com/track/4emicu3yCflgo7z6cAuw5j?si=9366104bd3c54a9f';
            app.Hyperlink.Text = 'click to listen!';
            app.niallanswersTab = uitab(app.TabGroup);
            app.niallanswersTab.Title = 'Tab5';
            app.soyourehappyandcomplicatedNiallPanel = uipanel(app.niallanswersTab);
            app.soyourehappyandcomplicatedNiallPanel.Title = 'So you''re happy and complicated...here''s your Niall Horan song recommendation!';
            app.soyourehappyandcomplicatedNiallPanel.Visible = 'off';
            app.EditField_21 = uieditfield(app.soyourehappyandcomplicatedNiallPanel, 'text');
            app.EditField_21.Value = 'San Francisco';
            app.Image6_37 = uiimage(app.soyourehappyandcomplicatedNiallPanel);
            app.Image6_37.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.28.08 PM.png');
            app.Hyperlink_9 = uihyperlink(app.soyourehappyandcomplicatedNiallPanel);
            app.Hyperlink_9.URL = 'https://open.spotify.com/track/5iSkdzgBBtAAkaqULGPZUd?si=3d44210f88504010';
            app.Hyperlink_9.Text = 'click to listen!';
            app.soyourehappyandsingleNiallPanel = uipanel(app.niallanswersTab);
            app.soyourehappyandsingleNiallPanel.Title = 'So you''re happy and single...here''s your Niall Horan song recommendation!';
            app.soyourehappyandsingleNiallPanel.Visible = 'off';
            app.EditField_22 = uieditfield(app.soyourehappyandsingleNiallPanel, 'text');
            app.EditField_22.Value = 'On My Own';
            app.Image6_38 = uiimage(app.soyourehappyandsingleNiallPanel);
            app.Image6_38.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.28.25 PM.png');
            app.Hyperlink_10 = uihyperlink(app.soyourehappyandsingleNiallPanel);
            app.Hyperlink_10.URL = 'https://open.spotify.com/track/2JL820UpxG8mLGWt1jjNRx?si=71bdb9cf476d4af7';
            app.Hyperlink_10.Text = 'click to listen!';
            app.soyouresadandcomplicatedNiallPanel = uipanel(app.niallanswersTab);
            app.soyouresadandcomplicatedNiallPanel.Title = 'So you''re sad and it''s complicated...here''s your Niall Horan song rec!';
            app.soyouresadandcomplicatedNiallPanel.Visible = 'off';
            app.EditField_23 = uieditfield(app.soyouresadandcomplicatedNiallPanel, 'text');
            app.EditField_23.Value = 'Too Much To Ask';
            app.Image6_39 = uiimage(app.soyouresadandcomplicatedNiallPanel);
            app.Image6_39.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.36.21 PM.png');
            app.Hyperlink_11 = uihyperlink(app.soyouresadandcomplicatedNiallPanel);
            app.Hyperlink_11.URL = 'https://open.spotify.com/track/39jXnbACjiaiTwwhsr5sY5?si=272009152c524f1a';
            app.Hyperlink_11.Text = 'click to listen!';
            app.soyouresadandinloveNiallPanel = uipanel(app.niallanswersTab);
            app.soyouresadandinloveNiallPanel.Title = 'So you''re sad and in love...here''s your Niall Horan  song recommendation!';
            app.soyouresadandinloveNiallPanel.Visible = 'off';
            app.EditField_24 = uieditfield(app.soyouresadandinloveNiallPanel, 'text');
            app.EditField_24.Value = 'Put a Little Love on Me';
            app.Image6_40 = uiimage(app.soyouresadandinloveNiallPanel);
            app.Image6_40.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.30.22 PM.png');
            app.Hyperlink_12 = uihyperlink(app.soyouresadandinloveNiallPanel);
            app.Hyperlink_12.URL = 'https://open.spotify.com/track/7LyBwug9tJgbaEzRCqtY78?si=d5aeb2591a1b4759';
            app.Hyperlink_12.Text = 'click to listen!';
            app.soyouresadandsingleNiallPanel = uipanel(app.niallanswersTab);
            app.soyouresadandsingleNiallPanel.Title = 'So you''re sad and single...here''s your Niall Horan song recommendation!';
            app.soyouresadandsingleNiallPanel.Visible = 'off';
            app.EditField_25 = uieditfield(app.soyouresadandsingleNiallPanel, 'text');
            app.EditField_25.Value = 'Dear Patience';
            app.Image6_41 = uiimage(app.soyouresadandsingleNiallPanel);
            app.Image6_41.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.34.18 PM.png');
            app.Hyperlink_13 = uihyperlink(app.soyouresadandsingleNiallPanel);
            app.Hyperlink_13.URL = 'https://open.spotify.com/track/0gwfuYQz0TauOajxH2V2Cn?si=49bee0b59ee3444f';
            app.Hyperlink_13.Text = 'click to listen!';
            app.soyouresadandheartbrokenNiallPanel = uipanel(app.niallanswersTab);
            app.soyouresadandheartbrokenNiallPanel.Title = 'So you''re sad and heartbroken...here''s your Niall Horan song recommendation!';
            app.soyouresadandheartbrokenNiallPanel.Visible = 'off';
            app.EditField_26 = uieditfield(app.soyouresadandheartbrokenNiallPanel, 'text');
            app.EditField_26.Value = 'Flicker';
            app.Image6_42 = uiimage(app.soyouresadandheartbrokenNiallPanel);
            app.Image6_42.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.33.03 PM.png');
            app.Hyperlink_14 = uihyperlink(app.soyouresadandheartbrokenNiallPanel);
            app.Hyperlink_14.URL = 'https://open.spotify.com/track/5BpmwiKR6ns1EtfCQRpNUc?si=767cfe407d6d4a7d';
            app.Hyperlink_14.Text = 'click to listen!';
            app.soyourehappyandinloveNiallPanel = uipanel(app.niallanswersTab);
            app.soyourehappyandinloveNiallPanel.Title = 'So you''re happy and in love...here''s your Niall Horan song recommendation!';
            app.soyourehappyandinloveNiallPanel.Visible = 'off';
            app.EditField_27 = uieditfield(app.soyourehappyandinloveNiallPanel, 'text');
            app.EditField_27.Value = 'Heaven';
            app.Image6_43 = uiimage(app.soyourehappyandinloveNiallPanel);
            app.Image6_43.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-17 at 1.17.52 PM.png');
            app.Hyperlink_15 = uihyperlink(app.soyourehappyandinloveNiallPanel);
            app.Hyperlink_15.URL = 'https://open.spotify.com/track/1yHVHoz6Ny29gbbWJYVnFt?si=d23fb875d06f45c7';
            app.Hyperlink_15.Text = 'click to listen!';

            app.soyourehappyandheartbrokenNiallPanel = uipanel(app.niallanswersTab);
            app.soyourehappyandheartbrokenNiallPanel.Title = 'So you''re happy and heartbroken...here''s your Niall Horan song rec!';
            app.soyourehappyandheartbrokenNiallPanel.Visible = 'off';
            app.EditField_28 = uieditfield(app.soyourehappyandheartbrokenNiallPanel, 'text');
            app.EditField_28.Value = 'Our Song';
            app.Image6_44 = uiimage(app.soyourehappyandheartbrokenNiallPanel);
            app.Image6_44.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-16 at 10.12.44 PM.png');
            app.Hyperlink_16 = uihyperlink(app.soyourehappyandheartbrokenNiallPanel);
            app.Hyperlink_16.URL = 'https://open.spotify.com/track/5zqObw7wjBgL9TDiAymxPn?si=00d2475732f840db';
            app.Hyperlink_16.Text = 'click to listen!';
            app.zaynanswersTab = uitab(app.TabGroup);
            app.zaynanswersTab.Title = 'Tab6';
            app.SoyourehappyandinloveZaynPanel = uipanel(app.zaynanswersTab);
            app.SoyourehappyandinloveZaynPanel.Title = 'So you''re happy and in love... here''s your Zayn song reccomendation!';
            app.SoyourehappyandinloveZaynPanel.Visible = 'off';
            app.EditField_29 = uieditfield(app.SoyourehappyandinloveZaynPanel, 'text');
            app.EditField_29.Value = 'Tightrope';
            app.Image6_45 = uiimage(app.SoyourehappyandinloveZaynPanel);
            app.Image6_45.ImageSource = '5775bc3d43736b50af40fc0e16548614.jpg';
            app.Hyperlink_17 = uihyperlink(app.SoyourehappyandinloveZaynPanel);
            app.Hyperlink_17.URL = 'https://open.spotify.com/track/44ngiCPfS8FfdJOqN0eULl?si=c815ffc6ee354da6';
            app.Hyperlink_17.Text = 'click to listen!';
            app.SoyourehappyandsingleZaynPanel = uipanel(app.zaynanswersTab);
            app.SoyourehappyandsingleZaynPanel.Title = 'So you''re happy and single... here''s your Zayn song reccomendation!';
            app.SoyourehappyandsingleZaynPanel.Visible = 'off';
            app.EditField_30 = uieditfield(app.SoyourehappyandsingleZaynPanel, 'text');
            app.EditField_30.Value = 'Good Guy';
            app.Image6_46 = uiimage(app.SoyourehappyandsingleZaynPanel);
            app.Image6_46.ImageSource = 'Screen Shot 2023-03-17 at 2.03.49 PM.png';
            app.Hyperlink_18 = uihyperlink(app.SoyourehappyandsingleZaynPanel);
            app.Hyperlink_18.URL = 'https://open.spotify.com/track/2a8kWoRITJO751JGbYVvCo?si=1ca06a580b5e4231';
            app.Hyperlink_18.Text = 'click to listen!';
            app.SoyourehappyandheartbrokenZaynPanel = uipanel(app.zaynanswersTab);
            app.SoyourehappyandheartbrokenZaynPanel.Title = 'So you''re happy and heartbroken... here''s your Zayn song reccomendation!';
            app.SoyourehappyandheartbrokenZaynPanel.Visible = 'off';
            app.EditField_31 = uieditfield(app.SoyourehappyandheartbrokenZaynPanel, 'text');
            app.EditField_31.Value = 'BeFoUr';
            app.Image6_47 = uiimage(app.SoyourehappyandheartbrokenZaynPanel);
            app.Image6_47.ImageSource = 'Screen Shot 2023-03-17 at 1.58.06 PM.png';
            app.Hyperlink_19 = uihyperlink(app.SoyourehappyandheartbrokenZaynPanel);
            app.Hyperlink_19.URL = 'https://open.spotify.com/track/7ofgPlilLgcw6G7fd2zNpT?si=3681178cbacf4b2b';
            app.Hyperlink_19.Text = 'click to listen!';
            app.SoyourehappyandcomplicatedZaynPanel = uipanel(app.zaynanswersTab);
            app.SoyourehappyandcomplicatedZaynPanel.Title = 'So you''re happy and it''s complicated... here''s your Zayn song reccomendation!';
            app.SoyourehappyandcomplicatedZaynPanel.Visible = 'off';
            app.EditField_32 = uieditfield(app.SoyourehappyandcomplicatedZaynPanel, 'text');
            app.EditField_32.Value = 'SHE DON''T LOVE ME';
            app.Image6_48 = uiimage(app.SoyourehappyandcomplicatedZaynPanel);
            app.Image6_48.ImageSource = '3db41c74ceed59b1ffd1f0a569474c4a.865x865x1.jpg';
            app.Hyperlink_20 = uihyperlink(app.SoyourehappyandcomplicatedZaynPanel);
            app.Hyperlink_20.URL = 'https://open.spotify.com/track/4MaOXwqSgiwgngbeYePmu8?si=a5b0e0626bb945ac';
            app.Hyperlink_20.Text = 'click to listen!';
            app.SoyouresadandinloveZaynPanel = uipanel(app.zaynanswersTab);
            app.SoyouresadandinloveZaynPanel.Title = 'So you''re sad and in love... here''s your Zayn song reccomendation!';
            app.SoyouresadandinloveZaynPanel.Visible = 'off';
            app.EditField_33 = uieditfield(app.SoyouresadandinloveZaynPanel, 'text');
            app.EditField_33.Value = 'When Love''s Around';
            app.Image6_49 = uiimage(app.SoyouresadandinloveZaynPanel);
            app.Image6_49.ImageSource = 'Screen Shot 2023-03-17 at 1.50.06 PM.png';
            app.Hyperlink_21 = uihyperlink(app.SoyouresadandinloveZaynPanel);
            app.Hyperlink_21.URL = 'https://open.spotify.com/track/4iC9bQomdbH5YPYi4HrECX?si=b6a10a74fb004077';
            app.Hyperlink_21.Text = 'click to listen!';
            app.SoyouresadandsingleZaynPanel = uipanel(app.zaynanswersTab);
            app.SoyouresadandsingleZaynPanel.Title = 'So you''re sad and single... here''s your Zayn song reccomendation!';
            app.SoyouresadandsingleZaynPanel.Visible = 'off';
            app.EditField_34 = uieditfield(app.SoyouresadandsingleZaynPanel, 'text');
            app.EditField_34.Value = 'Good Years';
            app.Image6_50 = uiimage(app.SoyouresadandsingleZaynPanel);
            app.Image6_50.ImageSource = 'Screen Shot 2023-03-19 at 8.54.01 PM.png';
            app.Hyperlink_22 = uihyperlink(app.SoyouresadandsingleZaynPanel);
            app.Hyperlink_22.URL = 'https://open.spotify.com/track/6CJLdpuz4utMTypcE8kUG3?si=d2bbdde5b15f4a95';
            app.Hyperlink_22.Text = 'click to listen!';
            app.SoyouresadandheartbrokenZaynPanel = uipanel(app.zaynanswersTab);
            app.SoyouresadandheartbrokenZaynPanel.Title = 'So you''re sad and heartbroken... here''s your Zayn song reccomendation!';
            app.SoyouresadandheartbrokenZaynPanel.Visible = 'off';
            app.EditField_35 = uieditfield(app.SoyouresadandheartbrokenZaynPanel, 'text');
            app.EditField_35.Value = 'Outside';
            app.Image6_51 = uiimage(app.SoyouresadandheartbrokenZaynPanel);
            app.Image6_51.ImageSource = '131ccdaf52d5b4bdc72f7cb03246f6ae.jpg';
            app.Hyperlink_23 = uihyperlink(app.SoyouresadandheartbrokenZaynPanel);
            app.Hyperlink_23.URL = 'https://open.spotify.com/track/3lYJHkGoC5b8TiwJnR65zp?si=35a3d283f5ff4243';
            app.Hyperlink_23.Text = 'click to listen!';
            app.SoyouresadandcomplicatedZaynPanel = uipanel(app.zaynanswersTab);
            app.SoyouresadandcomplicatedZaynPanel.Title = 'So you''re sad and it''s complicated... here''s your Zayn song reccomendation!';
            app.SoyouresadandcomplicatedZaynPanel.Visible = 'off';
            app.EditField_36 = uieditfield(app.SoyouresadandcomplicatedZaynPanel, 'text');
            app.EditField_36.Value = 'fOoL fOr YoU';
            app.Image6_52 = uiimage(app.SoyouresadandcomplicatedZaynPanel);
            app.Image6_52.ImageSource = 'dcd32a528f4948917b67a9a4d97ba014.jpg';
            app.Hyperlink_24 = uihyperlink(app.SoyouresadandcomplicatedZaynPanel);
            app.Hyperlink_24.URL = 'https://open.spotify.com/track/6lvsJDZ7336YmpBzcNGhbe?si=a6b740805d714196';
            app.Hyperlink_24.Text = 'click to listen!';
            app.liamanswersTab = uitab(app.TabGroup);
            app.liamanswersTab.Title = 'Tab7';
            app.soyourehappyandinloveLiamPanel = uipanel(app.liamanswersTab);
            app.soyourehappyandinloveLiamPanel.Title = 'So you''re happy and in love... here''s your Liam Payne song reccomendation!';
            app.soyourehappyandinloveLiamPanel.Visible = 'off';
            app.EditField_37 = uieditfield(app.soyourehappyandinloveLiamPanel, 'text');
            app.EditField_37.Value = 'Midnight';
            app.Image6_53 = uiimage(app.soyourehappyandinloveLiamPanel);
            app.Image6_53.ImageSource = fullfile(pathToMLAPP, 'EVGCtAnXgAcOcAp.jpg');
            app.Hyperlink_25 = uihyperlink(app.soyourehappyandinloveLiamPanel);
            app.Hyperlink_25.URL = 'https://open.spotify.com/track/4ZTTUAjJCd4NMZmIU7RB4c?si=8e9d3c0d4a1548ed';
            app.Hyperlink_25.Text = 'click to listen!';
            app.SoyourehappyandheartbrokenLiamPanel = uipanel(app.liamanswersTab);
            app.SoyourehappyandheartbrokenLiamPanel.Title = 'So you''re happy and heartbroken... here''s your Liam Payne song reccomendation!';
            app.SoyourehappyandheartbrokenLiamPanel.Visible = 'off';
            app.EditField_38 = uieditfield(app.SoyourehappyandheartbrokenLiamPanel, 'text');
            app.EditField_38.Value = 'Heart Meet Break';
            app.Image6_54 = uiimage(app.SoyourehappyandheartbrokenLiamPanel);
            app.Image6_54.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-19 at 9.35.33 PM.png');
            app.Hyperlink_26 = uihyperlink(app.SoyourehappyandheartbrokenLiamPanel);
            app.Hyperlink_26.URL = 'https://open.spotify.com/track/6fzpqUzBfEPJemXD6Bf1j1?si=af1a3191977342d2';
            app.Hyperlink_26.Text = 'click to listen!';
            app.SoyourehappyandsingleLiamPanel = uipanel(app.liamanswersTab);
            app.SoyourehappyandsingleLiamPanel.Title = 'So you''re happy and single... here''s your Liam Payne song reccomendation!';
            app.SoyourehappyandsingleLiamPanel.Visible = 'off';
            app.EditField_39 = uieditfield(app.SoyourehappyandsingleLiamPanel, 'text');
            app.EditField_39.Value = 'Sunshine';
            app.Image6_55 = uiimage(app.SoyourehappyandsingleLiamPanel);
            app.Image6_55.ImageSource = fullfile(pathToMLAPP, 'download.jpg');
            app.Hyperlink_27 = uihyperlink(app.SoyourehappyandsingleLiamPanel);
            app.Hyperlink_27.URL = 'https://open.spotify.com/track/48oN0dm8zwleoUvuLBqIOp?si=fc9aa874077d40dc';
            app.Hyperlink_27.Text = 'click to listen!';
            app.SoyourehappyandcomplicatedLiamPanel = uipanel(app.liamanswersTab);
            app.SoyourehappyandcomplicatedLiamPanel.Title = 'So you''re happy and it''s complicated... here''s your Liam Payne song reccomendation!';
            app.SoyourehappyandcomplicatedLiamPanel.Visible = 'off';
            app.EditField_40 = uieditfield(app.SoyourehappyandcomplicatedLiamPanel, 'text');
            app.EditField_40.Value = 'Tell Your Friends';
            app.Image6_56 = uiimage(app.SoyourehappyandcomplicatedLiamPanel);
            app.Image6_56.ImageSource = fullfile(pathToMLAPP, 'liam-payne-celebrates-his-brit-awards-2018-nominations-02.jpg');
            app.Hyperlink_28 = uihyperlink(app.SoyourehappyandcomplicatedLiamPanel);
            app.Hyperlink_28.URL = 'https://open.spotify.com/track/3H4VWHWeIUJ1w4adMQHVPE?si=f48d86606db54d54';
            app.Hyperlink_28.Text = 'click to listen!';
            app.SoyourehornyandheartbrokenLiamPanel = uipanel(app.liamanswersTab);
            app.SoyourehornyandheartbrokenLiamPanel.Title = 'So you''re horny and heartbroken... here''s your Liam Payne song reccomendation!';
            app.SoyourehornyandheartbrokenLiamPanel.Visible = 'off';
            app.EditField_42 = uieditfield(app.SoyourehornyandheartbrokenLiamPanel, 'text');
            app.EditField_42.Value = 'Polaroid';
            app.Image6_58 = uiimage(app.SoyourehornyandheartbrokenLiamPanel);
            app.Image6_58.ImageSource = fullfile(pathToMLAPP, 'PolaroidJonasBlue.jpg');
            app.Hyperlink_30 = uihyperlink(app.SoyourehornyandheartbrokenLiamPanel);
            app.Hyperlink_30.URL = 'https://open.spotify.com/track/49COPp81lGS9Hl9OwKlsKl?si=18654654dbcb4994';
            app.Hyperlink_30.Text = 'click to listen!';
            app.SoyourehornyandsingleLiamPanel = uipanel(app.liamanswersTab);
            app.SoyourehornyandsingleLiamPanel.Title = 'So you''re horny and single... here''s your Liam Payne song reccomendation!';
            app.SoyourehornyandsingleLiamPanel.Visible = 'off';
            app.EditField_43 = uieditfield(app.SoyourehornyandsingleLiamPanel, 'text');
            app.EditField_43.Value = 'Weekend';
            app.Image6_59 = uiimage(app.SoyourehornyandsingleLiamPanel);
            app.Image6_59.ImageSource = fullfile(pathToMLAPP, 'download copy.jpg');
            app.Hyperlink_31 = uihyperlink(app.SoyourehornyandsingleLiamPanel);
            app.Hyperlink_31.URL = 'https://open.spotify.com/track/6RfAYSRb4Scpz5BHsJ2vlG?si=97a5b8a696944708';
            app.Hyperlink_31.Text = 'click to listen!';
            app.SoyourehornyandcomplicatedLiamPanel = uipanel(app.liamanswersTab);
            app.SoyourehornyandcomplicatedLiamPanel.Title = 'So you''re horny and it''s complicated... here''s your Liam Payne song reccomendation!';
            app.SoyourehornyandcomplicatedLiamPanel.Visible = 'off';
            app.EditField_44 = uieditfield(app.SoyourehornyandcomplicatedLiamPanel, 'text');
            app.EditField_44.Value = 'Rude Hours';
            app.Image6_60 = uiimage(app.SoyourehornyandcomplicatedLiamPanel);
            app.Image6_60.ImageSource = fullfile(pathToMLAPP, 'Screen Shot 2023-03-19 at 9.50.47 PM.png');
            app.Hyperlink_32 = uihyperlink(app.SoyourehornyandcomplicatedLiamPanel);
            app.Hyperlink_32.URL = 'https://open.spotify.com/track/5Okihfwvzflkl37VsEJfXV?si=88c3f788763d4bbe';
            app.Hyperlink_32.Text = 'click to listen!';
            app.soyourehornyandinloveLiamPanel = uipanel(app.liamanswersTab);
            app.soyourehornyandinloveLiamPanel.Title = 'So you''re horny and in love... here''s your Liam Payne song reccomendation!';
            app.soyourehornyandinloveLiamPanel.Visible = 'off';
            app.EditField_41 = uieditfield(app.soyourehornyandinloveLiamPanel, 'text');
            app.EditField_41.Value = 'Strip That Down';
            app.Hyperlink_29 = uihyperlink(app.soyourehornyandinloveLiamPanel);
            app.Hyperlink_29.URL = 'https://open.spotify.com/track/4Ro98RCK90oHqqSZUnTFq5?si=9b320a8f2ae64a90';
            app.Hyperlink_29.Text = 'click to listen!';
            app.stripthatdowngif = uibutton(app.soyourehornyandinloveLiamPanel, 'push');
            app.stripthatdowngif.Icon = fullfile(pathToMLAPP, 'liam-payne-liam-payne-dancing.gif');
            app.stripthatdowngif.Text = '';
            app.tayloranswersTab = uitab(app.TabGroup);
            app.tayloranswersTab.Title = 'Tab8';
            app.soyourehappyandinloveTaylorPanel = uipanel(app.tayloranswersTab);
            app.soyourehappyandinloveTaylorPanel.Title = 'So you''re happy and in love...here''s your Taylor Swift song recommendation!';
            app.soyourehappyandinloveTaylorPanel.Visible = 'off';
            app.EditField_45 = uieditfield(app.soyourehappyandinloveTaylorPanel, 'text');
            app.EditField_45.Value = 'Lover';
            app.Image6_61 = uiimage(app.soyourehappyandinloveTaylorPanel);
            app.Image6_61.ImageSource = fullfile(pathToMLAPP, 'Lover.jpg');
            app.Hyperlink_33 = uihyperlink(app.soyourehappyandinloveTaylorPanel);
            app.Hyperlink_33.URL = 'https://open.spotify.com/track/1dGr1c8CrMLDpV6mPbImSI?si=326ecae2aa1241b4';
            app.Hyperlink_33.Text = 'click to listen!';
            app.soyourehappyandsingleTaylorPanel = uipanel(app.tayloranswersTab);
            app.soyourehappyandsingleTaylorPanel.Title = 'So you''re happy and single...here''s your Taylor Swift song recommendation!';
            app.soyourehappyandsingleTaylorPanel.Visible = 'off';
            app.EditField_46 = uieditfield(app.soyourehappyandsingleTaylorPanel, 'text');
            app.EditField_46.Value = 'I Did Something Bad';
            app.Image6_62 = uiimage(app.soyourehappyandsingleTaylorPanel);
            app.Image6_62.ImageSource = fullfile(pathToMLAPP, 'I DID SOMETHING BAD TS POLAROID.jpg');
            app.Hyperlink_34 = uihyperlink(app.soyourehappyandsingleTaylorPanel);
            app.Hyperlink_34.URL = 'https://open.spotify.com/track/4svZDCRz4cJoneBpjpx8DJ?si=52b516a86d594e45';
            app.Hyperlink_34.Text = 'click to listen!';
            app.soyourehappyandheartbrokenTaylorPanel = uipanel(app.tayloranswersTab);
            app.soyourehappyandheartbrokenTaylorPanel.Title = 'So you''re happy and heartbroken...here''s your Taylor Swift song recommendation!';
            app.soyourehappyandheartbrokenTaylorPanel.Visible = 'off';
            app.EditField_47 = uieditfield(app.soyourehappyandheartbrokenTaylorPanel, 'text');
            app.EditField_47.Value = 'Cruel Summer';
            app.Image6_63 = uiimage(app.soyourehappyandheartbrokenTaylorPanel);
            app.Image6_63.ImageSource = fullfile(pathToMLAPP, 'Lockscreen taylor swift.jpg');
            app.Hyperlink_35 = uihyperlink(app.soyourehappyandheartbrokenTaylorPanel);
            app.Hyperlink_35.URL = 'https://open.spotify.com/track/1BxfuPKGuaTgP7aM0Bbdwr?si=9094df9a79274375';
            app.Hyperlink_35.Text = 'click to listen!';
            app.soyourehappyandcomplicatedTaylorPanel = uipanel(app.tayloranswersTab);
            app.soyourehappyandcomplicatedTaylorPanel.Title = 'So you''re happy and it''s complicated...here''s your Taylor Swift song recommendation!';
            app.soyourehappyandcomplicatedTaylorPanel.Visible = 'off';
            app.EditField_48 = uieditfield(app.soyourehappyandcomplicatedTaylorPanel, 'text');
            app.EditField_48.Value = 'Getaway Car';
            app.Image6_64 = uiimage(app.soyourehappyandcomplicatedTaylorPanel);
            app.Image6_64.ImageSource = fullfile(pathToMLAPP, 'taylor swift getaway car reputation minimalist poster polaroid.jpg');
            app.Hyperlink_36 = uihyperlink(app.soyourehappyandcomplicatedTaylorPanel);
            app.Hyperlink_36.URL = 'https://open.spotify.com/track/0VE4kBnHJUgtMf0dy6DRmW?si=8df8d774736a4998';
            app.Hyperlink_36.Text = 'click to listen!';
            app.soyouresadandinloveTaylorPanel = uipanel(app.tayloranswersTab);
            app.soyouresadandinloveTaylorPanel.Title = 'So you''re sad and in love...here''s your Taylor Swift song recommendation!';
            app.soyouresadandinloveTaylorPanel.Visible = 'off';
            app.EditField_49 = uieditfield(app.soyouresadandinloveTaylorPanel, 'text');
            app.EditField_49.Value = 'Wildest Dreams';
            app.Image6_65 = uiimage(app.soyouresadandinloveTaylorPanel);
            app.Image6_65.ImageSource = fullfile(pathToMLAPP, 'Wildest Dreams.jpg');
            app.Hyperlink_37 = uihyperlink(app.soyouresadandinloveTaylorPanel);
            app.Hyperlink_37.URL = 'https://open.spotify.com/track/1Ov37jtRQ2YNAe8HzfczkL?si=100e954647584f38';
            app.Hyperlink_37.Text = 'click to listen!';
            app.soyouresadandheartbrokenTaylorPanel = uipanel(app.tayloranswersTab);
            app.soyouresadandheartbrokenTaylorPanel.Title = 'So you''re sad and in heartbroken...here''s your Taylor Swift song recommendation!';
            app.soyouresadandheartbrokenTaylorPanel.Visible = 'off';
            app.EditField_50 = uieditfield(app.soyouresadandheartbrokenTaylorPanel, 'text');
            app.EditField_50.Value = 'All Too Well (10 Minute Version)';
            app.Image6_66 = uiimage(app.soyouresadandheartbrokenTaylorPanel);
            app.Image6_66.ImageSource = fullfile(pathToMLAPP, 'short film poster Poster by dontwannadance.jpg');
            app.Hyperlink_38 = uihyperlink(app.soyouresadandheartbrokenTaylorPanel);
            app.Hyperlink_38.URL = 'https://open.spotify.com/track/5enxwA8aAbwZbf5qCHORXi?si=4d775f91b48f4479';
            app.Hyperlink_38.Text = 'click to listen!';
            app.soyouresadandsingleTaylorPanel = uipanel(app.tayloranswersTab);
            app.soyouresadandsingleTaylorPanel.Title = 'So you''re sad and single...here''s your Taylor Swift song recommendation!';
            app.soyouresadandsingleTaylorPanel.Visible = 'off';
            app.EditField_51 = uieditfield(app.soyouresadandsingleTaylorPanel, 'text');
            app.EditField_51.Value = 'The Archer';
            app.Image6_67 = uiimage(app.soyouresadandsingleTaylorPanel);
            app.Image6_67.ImageSource = fullfile(pathToMLAPP, 'Taylor Swift Wallpaper.jpg');
            app.Hyperlink_39 = uihyperlink(app.soyouresadandsingleTaylorPanel);
            app.Hyperlink_39.URL = 'https://open.spotify.com/track/3pHkh7d0lzM2AldUtz2x37?si=0167078cfe214fa3';
            app.Hyperlink_39.Text = 'click to listen!';
            app.soyouresadandcomplicatedTaylorPanel = uipanel(app.tayloranswersTab);
            app.soyouresadandcomplicatedTaylorPanel.Title = 'So you''re sad and it''s complicated...here''s your Taylor Swift song recommendation!';
            app.soyouresadandcomplicatedTaylorPanel.Visible = 'off';
            app.EditField_52 = uieditfield(app.soyouresadandcomplicatedTaylorPanel, 'text');
            app.EditField_52.Value = 'illicit affairs';
            app.Image6_68 = uiimage(app.soyouresadandcomplicatedTaylorPanel);
            app.Image6_68.ImageSource = fullfile(pathToMLAPP, 'illicit affairs polaroid song poster ver_2  _@livyly13_.jpg');
            app.Hyperlink_40 = uihyperlink(app.soyouresadandcomplicatedTaylorPanel);
            app.Hyperlink_40.URL = 'https://open.spotify.com/track/2NmsngXHeC1GQ9wWrzhOMf?si=3e0f9ee6356d444a';
            app.Hyperlink_40.Text = 'click to listen!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tryingtabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end